classdef dual_radar_base_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ThetaPositionmmEditField      matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel      matlab.ui.control.Label
        ZPositionmmEditField          matlab.ui.control.NumericEditField
        ZPositionmmEditFieldLabel     matlab.ui.control.Label
        XPositionmmEditField          matlab.ui.control.NumericEditField
        XPositionmmEditFieldLabel     matlab.ui.control.Label
        MainTextArea                  matlab.ui.control.TextArea
        ControlSingleMovementPanel    matlab.ui.container.Panel
        StopAllButton                 matlab.ui.control.Button
        SendSingleMovementButton      matlab.ui.control.Button
        ThetaStepSizemmEditField      matlab.ui.control.NumericEditField
        StepSizemmLabel               matlab.ui.control.Label
        ZStepSizemmEditField          matlab.ui.control.NumericEditField
        ZStepSizemmLabel              matlab.ui.control.Label
        XStepSizemmEditField          matlab.ui.control.NumericEditField
        XStepSizemmLabel              matlab.ui.control.Label
        ConnectionConfigurationPanel  matlab.ui.container.Panel
        AMC4030ConfigurationLamp      matlab.ui.control.Lamp
        AMC4030ConnectionLamp         matlab.ui.control.Lamp
        ConfigureAMC4030Button        matlab.ui.control.Button
        ConnectAMC4030Button          matlab.ui.control.Button
        ThetaAxisPanel                matlab.ui.container.Panel
        ThetaHomeButton               matlab.ui.control.Button
        ThetaMaximummmEditField       matlab.ui.control.NumericEditField
        MaximummmEditField_3Label     matlab.ui.control.Label
        ThetaHomeoffsetmmEditField    matlab.ui.control.NumericEditField
        HomeoffsetmmEditField_3Label  matlab.ui.control.Label
        ThetaHomespeedmmsEditField    matlab.ui.control.NumericEditField
        HomespeeddegsLabel            matlab.ui.control.Label
        ThetapulsesrevEditField       matlab.ui.control.NumericEditField
        pulsesrevLabel_3              matlab.ui.control.Label
        ThetammrevEditField           matlab.ui.control.NumericEditField
        mmrevLabel_3                  matlab.ui.control.Label
        ThetaSpeedmmsEditField        matlab.ui.control.NumericEditField
        SpeeddegsLabel                matlab.ui.control.Label
        ZAxisPanel                    matlab.ui.container.Panel
        ZHomeButton                   matlab.ui.control.Button
        ZMaximummmEditField           matlab.ui.control.NumericEditField
        MaximummmEditField_2Label     matlab.ui.control.Label
        ZHomeoffsetmmEditField        matlab.ui.control.NumericEditField
        HomeoffsetmmEditField_2Label  matlab.ui.control.Label
        ZHomespeedmmsEditField        matlab.ui.control.NumericEditField
        HomespeedmmsEditField_2Label  matlab.ui.control.Label
        ZpulsesrevEditField           matlab.ui.control.NumericEditField
        pulsesrevLabel_2              matlab.ui.control.Label
        ZmmrevEditField               matlab.ui.control.NumericEditField
        mmrevLabel_2                  matlab.ui.control.Label
        ZSpeedmmsEditField            matlab.ui.control.NumericEditField
        SpeedmmsEditField_2Label      matlab.ui.control.Label
        XAxisPanel                    matlab.ui.container.Panel
        XHomeButton                   matlab.ui.control.Button
        XMaximummmEditField           matlab.ui.control.NumericEditField
        MaximummmEditFieldLabel       matlab.ui.control.Label
        XHomeoffsetmmEditField        matlab.ui.control.NumericEditField
        HomeoffsetmmEditFieldLabel    matlab.ui.control.Label
        XHomespeedmmsEditField        matlab.ui.control.NumericEditField
        HomespeedmmsEditFieldLabel    matlab.ui.control.Label
        XpulsesrevEditField           matlab.ui.control.NumericEditField
        pulsesrevLabel                matlab.ui.control.Label
        XmmrevEditField               matlab.ui.control.NumericEditField
        mmrevLabel                    matlab.ui.control.Label
        XSpeedmmsEditField            matlab.ui.control.NumericEditField
        SpeedmmsEditFieldLabel        matlab.ui.control.Label
        DualRadarBaseControllerLabel  matlab.ui.control.Label
    end

    
    properties
        amc
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folders to the path
            splitPath = regexp(cd,filesep,'split');
            if string(splitPath{end}) ~= "dual-radar-gui"
                try
                    cd(fileparts(which('dual_radar_gui.mlapp')));
                catch
                end
            end

            splitPath = regexp(cd,filesep,'split');
            while string(splitPath{end}) ~= "dual-radar-gui"
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct "dual-radar-gui" folder?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app,0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""dual-radar-gui"" Path");
                cd(directoryname);
                splitPath = regexp(cd,filesep,'split');
            end
            addpath(genpath("./"));
            
            startupDRB(app);
        end

        % Button pushed function: ConnectAMC4030Button
        function ConnectAMC4030ButtonPushed(app, event)
            app.amc.SerialConnect();
        end

        % Button pushed function: ConfigureAMC4030Button
        function ConfigureAMC4030ButtonPushed(app, event)
            app.amc.Configure();
        end

        % Button pushed function: XHomeButton
        function XHomeButtonPushed(app, event)
            if app.amc.Home_AMC4030(1,0,0) == 1
                app.XPositionmmEditField.Value = 0;
            end
        end

        % Button pushed function: ZHomeButton
        function ZHomeButtonPushed(app, event)
            if app.amc.Home_AMC4030(0,1,0) == 1
                app.ZPositionmmEditField.Value = 0;
            end
        end

        % Button pushed function: SendSingleMovementButton
        function SendSingleMovementButtonPushed(app, event)
            x_move_mm = app.XStepSizemmEditField.Value;
            z_move_mm = app.ZStepSizemmEditField.Value;
            theta_move_mm = app.ThetaStepSizemmEditField.Value;

            app.amc.SingleCommand(x_move_mm,z_move_mm,theta_move_mm);
        end

        % Button pushed function: StopAllButton
        function StopAllButtonPushed(app, event)
            app.amc.Stop_All();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DualRadarBaseControllerLabel = uilabel(app.UIFigure);
            app.DualRadarBaseControllerLabel.Text = 'Dual Radar Base Controller';
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X-Axis';
            app.SpeedmmsEditFieldLabel = uilabel(app.XAxisPanel);
            app.SpeedmmsEditFieldLabel.Text = 'Speed (mm/s)';
            app.XSpeedmmsEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XSpeedmmsEditField.Limits = [0 Inf];
            app.XSpeedmmsEditField.RoundFractionalValues = 'on';
            app.XSpeedmmsEditField.ValueDisplayFormat = '%d';
            app.XSpeedmmsEditField.Value = 20;
            app.mmrevLabel = uilabel(app.XAxisPanel);
            app.mmrevLabel.Text = 'mm / rev';
            app.XmmrevEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XmmrevEditField.Limits = [0 Inf];
            app.XmmrevEditField.RoundFractionalValues = 'on';
            app.XmmrevEditField.ValueDisplayFormat = '%d';
            app.XmmrevEditField.Value = 5;
            app.pulsesrevLabel = uilabel(app.XAxisPanel);
            app.pulsesrevLabel.Text = 'pulses / rev';
            app.XpulsesrevEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XpulsesrevEditField.Limits = [0 Inf];
            app.XpulsesrevEditField.RoundFractionalValues = 'on';
            app.XpulsesrevEditField.ValueDisplayFormat = '%d';
            app.XpulsesrevEditField.Value = 20000;
            app.HomespeedmmsEditFieldLabel = uilabel(app.XAxisPanel);
            app.HomespeedmmsEditFieldLabel.Text = 'Home speed (mm/s)';
            app.XHomespeedmmsEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XHomespeedmmsEditField.Limits = [0 Inf];
            app.XHomespeedmmsEditField.RoundFractionalValues = 'on';
            app.XHomespeedmmsEditField.ValueDisplayFormat = '%d';
            app.XHomespeedmmsEditField.Value = 5;
            app.HomeoffsetmmEditFieldLabel = uilabel(app.XAxisPanel);
            app.HomeoffsetmmEditFieldLabel.Text = 'Home offset (mm)';
            app.XHomeoffsetmmEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XHomeoffsetmmEditField.Limits = [0 Inf];
            app.XHomeoffsetmmEditField.RoundFractionalValues = 'on';
            app.XHomeoffsetmmEditField.ValueDisplayFormat = '%d';
            app.MaximummmEditFieldLabel = uilabel(app.XAxisPanel);
            app.MaximummmEditFieldLabel.Text = 'Maximum (mm)';
            app.XMaximummmEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XMaximummmEditField.Limits = [0 Inf];
            app.XMaximummmEditField.RoundFractionalValues = 'on';
            app.XMaximummmEditField.ValueDisplayFormat = '%d';
            app.XMaximummmEditField.Value = 400;
            app.XHomeButton = uibutton(app.XAxisPanel, 'push');
            app.XHomeButton.ButtonPushedFcn = createCallbackFcn(app, @XHomeButtonPushed, true);
            app.XHomeButton.Text = 'Home';
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z-Axis';
            app.SpeedmmsEditField_2Label = uilabel(app.ZAxisPanel);
            app.SpeedmmsEditField_2Label.Text = 'Speed (mm/s)';
            app.ZSpeedmmsEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZSpeedmmsEditField.Limits = [0 Inf];
            app.ZSpeedmmsEditField.RoundFractionalValues = 'on';
            app.ZSpeedmmsEditField.ValueDisplayFormat = '%d';
            app.ZSpeedmmsEditField.Value = 20;
            app.mmrevLabel_2 = uilabel(app.ZAxisPanel);
            app.mmrevLabel_2.Text = 'mm / rev';
            app.ZmmrevEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZmmrevEditField.Limits = [0 Inf];
            app.ZmmrevEditField.RoundFractionalValues = 'on';
            app.ZmmrevEditField.ValueDisplayFormat = '%d';
            app.ZmmrevEditField.Value = 5;
            app.pulsesrevLabel_2 = uilabel(app.ZAxisPanel);
            app.pulsesrevLabel_2.Text = 'pulses / rev';
            app.ZpulsesrevEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZpulsesrevEditField.Limits = [0 Inf];
            app.ZpulsesrevEditField.RoundFractionalValues = 'on';
            app.ZpulsesrevEditField.ValueDisplayFormat = '%d';
            app.ZpulsesrevEditField.Value = 20000;
            app.HomespeedmmsEditField_2Label = uilabel(app.ZAxisPanel);
            app.HomespeedmmsEditField_2Label.Text = 'Home speed (mm/s)';
            app.ZHomespeedmmsEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZHomespeedmmsEditField.Limits = [0 Inf];
            app.ZHomespeedmmsEditField.RoundFractionalValues = 'on';
            app.ZHomespeedmmsEditField.ValueDisplayFormat = '%d';
            app.ZHomespeedmmsEditField.Value = 5;
            app.HomeoffsetmmEditField_2Label = uilabel(app.ZAxisPanel);
            app.HomeoffsetmmEditField_2Label.Text = 'Home offset (mm)';
            app.ZHomeoffsetmmEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZHomeoffsetmmEditField.Limits = [0 Inf];
            app.ZHomeoffsetmmEditField.RoundFractionalValues = 'on';
            app.ZHomeoffsetmmEditField.ValueDisplayFormat = '%d';
            app.MaximummmEditField_2Label = uilabel(app.ZAxisPanel);
            app.MaximummmEditField_2Label.Text = 'Maximum (mm)';
            app.ZMaximummmEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZMaximummmEditField.Limits = [0 Inf];
            app.ZMaximummmEditField.RoundFractionalValues = 'on';
            app.ZMaximummmEditField.ValueDisplayFormat = '%d';
            app.ZMaximummmEditField.Value = 400;
            app.ZHomeButton = uibutton(app.ZAxisPanel, 'push');
            app.ZHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ZHomeButtonPushed, true);
            app.ZHomeButton.Text = 'Home';
            app.ThetaAxisPanel = uipanel(app.UIFigure);
            app.ThetaAxisPanel.Title = 'θ-Axis';
            app.SpeeddegsLabel = uilabel(app.ThetaAxisPanel);
            app.SpeeddegsLabel.Text = 'Speed (deg/s)';
            app.ThetaSpeedmmsEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetaSpeedmmsEditField.Limits = [0 Inf];
            app.ThetaSpeedmmsEditField.RoundFractionalValues = 'on';
            app.ThetaSpeedmmsEditField.ValueDisplayFormat = '%d';
            app.ThetaSpeedmmsEditField.Value = 20;
            app.mmrevLabel_3 = uilabel(app.ThetaAxisPanel);
            app.mmrevLabel_3.Text = 'mm / rev';
            app.ThetammrevEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetammrevEditField.Limits = [0 Inf];
            app.ThetammrevEditField.RoundFractionalValues = 'on';
            app.ThetammrevEditField.ValueDisplayFormat = '%d';
            app.ThetammrevEditField.Value = 36;
            app.pulsesrevLabel_3 = uilabel(app.ThetaAxisPanel);
            app.pulsesrevLabel_3.Text = 'pulses / rev';
            app.ThetapulsesrevEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetapulsesrevEditField.Limits = [0 Inf];
            app.ThetapulsesrevEditField.RoundFractionalValues = 'on';
            app.ThetapulsesrevEditField.ValueDisplayFormat = '%d';
            app.ThetapulsesrevEditField.Value = 20000;
            app.HomespeeddegsLabel = uilabel(app.ThetaAxisPanel);
            app.HomespeeddegsLabel.Text = 'Home speed (deg/s)';
            app.ThetaHomespeedmmsEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetaHomespeedmmsEditField.Limits = [0 Inf];
            app.ThetaHomespeedmmsEditField.RoundFractionalValues = 'on';
            app.ThetaHomespeedmmsEditField.ValueDisplayFormat = '%d';
            app.ThetaHomespeedmmsEditField.Editable = 'off';
            app.ThetaHomespeedmmsEditField.Enable = 'off';
            app.ThetaHomespeedmmsEditField.Value = 5;
            app.HomeoffsetmmEditField_3Label = uilabel(app.ThetaAxisPanel);
            app.HomeoffsetmmEditField_3Label.Text = 'Home offset (mm)';
            app.ThetaHomeoffsetmmEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetaHomeoffsetmmEditField.Limits = [0 Inf];
            app.ThetaHomeoffsetmmEditField.RoundFractionalValues = 'on';
            app.ThetaHomeoffsetmmEditField.ValueDisplayFormat = '%d';
            app.ThetaHomeoffsetmmEditField.Editable = 'off';
            app.ThetaHomeoffsetmmEditField.Enable = 'off';
            app.MaximummmEditField_3Label = uilabel(app.ThetaAxisPanel);
            app.MaximummmEditField_3Label.Text = 'Maximum (mm)';
            app.ThetaMaximummmEditField = uieditfield(app.ThetaAxisPanel, 'numeric');
            app.ThetaMaximummmEditField.Limits = [0 Inf];
            app.ThetaMaximummmEditField.RoundFractionalValues = 'on';
            app.ThetaMaximummmEditField.ValueDisplayFormat = '%d';
            app.ThetaMaximummmEditField.Editable = 'off';
            app.ThetaMaximummmEditField.Enable = 'off';
            app.ThetaHomeButton = uibutton(app.ThetaAxisPanel, 'push');
            app.ThetaHomeButton.Enable = 'off';
            app.ThetaHomeButton.Text = 'Home';
            app.ConnectionConfigurationPanel = uipanel(app.UIFigure);
            app.ConnectionConfigurationPanel.Title = 'Connection / Configuration';
            app.ConnectAMC4030Button = uibutton(app.ConnectionConfigurationPanel, 'push');
            app.ConnectAMC4030Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectAMC4030ButtonPushed, true);
            app.ConnectAMC4030Button.Text = 'Connect AMC4030';
            app.ConfigureAMC4030Button = uibutton(app.ConnectionConfigurationPanel, 'push');
            app.ConfigureAMC4030Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureAMC4030ButtonPushed, true);
            app.ConfigureAMC4030Button.Text = 'Configure AMC4030';
            app.AMC4030ConnectionLamp = uilamp(app.ConnectionConfigurationPanel);
            app.AMC4030ConfigurationLamp = uilamp(app.ConnectionConfigurationPanel);
            app.ControlSingleMovementPanel = uipanel(app.UIFigure);
            app.ControlSingleMovementPanel.Title = 'Control (Single Movement)';
            app.XStepSizemmLabel = uilabel(app.ControlSingleMovementPanel);
            app.XStepSizemmLabel.Text = 'X-Step Size (mm)';
            app.XStepSizemmEditField = uieditfield(app.ControlSingleMovementPanel, 'numeric');
            app.XStepSizemmEditField.RoundFractionalValues = 'on';
            app.XStepSizemmEditField.ValueDisplayFormat = '%d';
            app.XStepSizemmEditField.Value = 20;
            app.ZStepSizemmLabel = uilabel(app.ControlSingleMovementPanel);
            app.ZStepSizemmLabel.Text = 'Z-Step Size (mm)';
            app.ZStepSizemmEditField = uieditfield(app.ControlSingleMovementPanel, 'numeric');
            app.ZStepSizemmEditField.RoundFractionalValues = 'on';
            app.ZStepSizemmEditField.ValueDisplayFormat = '%d';
            app.ZStepSizemmEditField.Value = 20;
            app.StepSizemmLabel = uilabel(app.ControlSingleMovementPanel);
            app.StepSizemmLabel.Text = 'θ-Step Size (mm)';
            app.ThetaStepSizemmEditField = uieditfield(app.ControlSingleMovementPanel, 'numeric');
            app.ThetaStepSizemmEditField.RoundFractionalValues = 'on';
            app.ThetaStepSizemmEditField.ValueDisplayFormat = '%d';
            app.ThetaStepSizemmEditField.Value = 20;
            app.SendSingleMovementButton = uibutton(app.ControlSingleMovementPanel, 'push');
            app.SendSingleMovementButton.ButtonPushedFcn = createCallbackFcn(app, @SendSingleMovementButtonPushed, true);
            app.SendSingleMovementButton.Text = 'Send';
            app.StopAllButton = uibutton(app.ControlSingleMovementPanel, 'push');
            app.StopAllButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllButtonPushed, true);
            app.StopAllButton.Text = 'STOP';
            app.MainTextArea = uitextarea(app.UIFigure);
            app.XPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.XPositionmmEditFieldLabel.Text = 'X-Position (mm)';
            app.XPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.XPositionmmEditField.Editable = 'off';
            app.ZPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZPositionmmEditFieldLabel.Text = 'Z-Position (mm)';
            app.ZPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.ZPositionmmEditField.Editable = 'off';
            app.PositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.PositionmmEditFieldLabel.Text = 'θ-Position (mm)';
            app.ThetaPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.ThetaPositionmmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dual_radar_base_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end