classdef dual_radar_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EmergencyStopButton             matlab.ui.control.Button
        YPositionmmEditField            matlab.ui.control.NumericEditField
        YPositionmmEditFieldLabel       matlab.ui.control.Label
        PeriodicitymsEditField_3        matlab.ui.control.NumericEditField
        PeriodicitymsEditField_3Label   matlab.ui.control.Label
        XPositionmmEditField            matlab.ui.control.NumericEditField
        XPositionmmEditFieldLabel       matlab.ui.control.Label
        NoofFramesEditField_3           matlab.ui.control.NumericEditField
        NoofFramesEditField_3Label      matlab.ui.control.Label
        RampEndTimeusEditField_3        matlab.ui.control.NumericEditField
        RampEndTimeusEditField_3Label   matlab.ui.control.Label
        SampleRatekspsEditField_3       matlab.ui.control.NumericEditField
        SampleRatekspsEditField_3Label  matlab.ui.control.Label
        ADCSamplesEditField_3           matlab.ui.control.NumericEditField
        ADCSamplesEditField_3Label      matlab.ui.control.Label
        ADCStartTimeusEditField_3       matlab.ui.control.NumericEditField
        ADCStartTimeusEditField_3Label  matlab.ui.control.Label
        MainTextArea                    matlab.ui.control.TextArea
        ConfigurationPanel              matlab.ui.container.Panel
        SynchronizerConfigurationLamp   matlab.ui.control.Lamp
        SynchronizerConfigurationLabel  matlab.ui.control.Label
        Radar1ConfigurationLabel        matlab.ui.control.Label
        Radar2ConfigurationLabel        matlab.ui.control.Label
        MotionControllerConfigurationLabel  matlab.ui.control.Label
        Radar1ConfigurationLamp         matlab.ui.control.Lamp
        Radar2ConfigurationLamp         matlab.ui.control.Lamp
        MotionControllerConfigurationLamp  matlab.ui.control.Lamp
        ConnectionsPanel                matlab.ui.container.Panel
        SynchronizerConnectionLamp      matlab.ui.control.Lamp
        SynchronizerConnectionLabel     matlab.ui.control.Label
        Radar1ConnectionLabel           matlab.ui.control.Label
        Radar2ConnectionLabel           matlab.ui.control.Label
        MotionControllerConnectionLabel  matlab.ui.control.Label
        Radar1ConnectionLamp            matlab.ui.control.Lamp
        Radar2ConnectionLamp            matlab.ui.control.Lamp
        MotionControllerConnectionLamp  matlab.ui.control.Lamp
        AuthorJosiahWSmithAdvisorMuratTorlakLabel  matlab.ui.control.Label
        DualRadarSARControllerLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RadarSetupTab                   matlab.ui.container.Tab
        DCA2ConfigurationPanel          matlab.ui.container.Panel
        DCA2PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA2Button               matlab.ui.control.Button
        DataPortEditField_2             matlab.ui.control.EditField
        DataPortEditField_2Label        matlab.ui.control.Label
        ConfigPortEditField_2           matlab.ui.control.EditField
        ConfigPortEditField_2Label      matlab.ui.control.Label
        DCAIPAddressEditField_2         matlab.ui.control.EditField
        DCAIPAddressEditField_2Label    matlab.ui.control.Label
        SystemIPAddressEditField_2      matlab.ui.control.EditField
        SystemIPAddressEditField_2Label  matlab.ui.control.Label
        DCA1ConfigurationPanel          matlab.ui.container.Panel
        DCA1PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA1Button               matlab.ui.control.Button
        DataPortEditField               matlab.ui.control.EditField
        DataPortEditFieldLabel          matlab.ui.control.Label
        ConfigPortEditField             matlab.ui.control.EditField
        ConfigPortEditFieldLabel        matlab.ui.control.Label
        DCAIPAddressEditField           matlab.ui.control.EditField
        DCAIPAddressEditFieldLabel      matlab.ui.control.Label
        SystemIPAddressEditField        matlab.ui.control.EditField
        SystemIPAddressEditFieldLabel   matlab.ui.control.Label
        Radar277GHzConfigurationPanel   matlab.ui.container.Panel
        StopButton_2                    matlab.ui.control.Button
        StartButton_2                   matlab.ui.control.Button
        Tx1CheckBox_2                   matlab.ui.control.CheckBox
        Tx0CheckBox_2                   matlab.ui.control.CheckBox
        Rx2CheckBox_2                   matlab.ui.control.CheckBox
        Rx3CheckBox_2                   matlab.ui.control.CheckBox
        Rx1CheckBox_2                   matlab.ui.control.CheckBox
        Rx0CheckBox_2                   matlab.ui.control.CheckBox
        ConfigureRadar2Button           matlab.ui.control.Button
        PeriodicitymsEditField_2        matlab.ui.control.NumericEditField
        PeriodicitymsEditField_2Label   matlab.ui.control.Label
        NoofChirpLoopsEditField_2       matlab.ui.control.NumericEditField
        NoofChirpLoopsEditField_2Label  matlab.ui.control.Label
        NoofFramesEditField_2           matlab.ui.control.NumericEditField
        NoofFramesEditField_2Label      matlab.ui.control.Label
        RampEndTimeusEditField_2        matlab.ui.control.NumericEditField
        RampEndTimeusEditField_2Label   matlab.ui.control.Label
        SampleRatekspsEditField_2       matlab.ui.control.NumericEditField
        SampleRatekspsEditField_2Label  matlab.ui.control.Label
        ADCSamplesEditField_2           matlab.ui.control.NumericEditField
        ADCSamplesEditField_2Label      matlab.ui.control.Label
        ADCStartTimeusEditField_2       matlab.ui.control.NumericEditField
        ADCStartTimeusEditField_2Label  matlab.ui.control.Label
        TXStartTimeusEditField_2        matlab.ui.control.NumericEditField
        TXStartTimeusEditField_2Label   matlab.ui.control.Label
        IdleTimeusEditField_2           matlab.ui.control.NumericEditField
        IdleTimeusEditField_2Label      matlab.ui.control.Label
        FreqSlopeMHzusEditField_2       matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditField_2Label  matlab.ui.control.Label
        StartFreqGHzEditField_2         matlab.ui.control.NumericEditField
        StartFreqGHzEditField_2Label    matlab.ui.control.Label
        SerialConnectionsPanel          matlab.ui.container.Panel
        ConnectRadar2Button             matlab.ui.control.Button
        ConnectRadar1Button             matlab.ui.control.Button
        Radar160GHzConfigurationPanel   matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Tx2CheckBox                     matlab.ui.control.CheckBox
        Tx1CheckBox                     matlab.ui.control.CheckBox
        Tx0CheckBox                     matlab.ui.control.CheckBox
        Rx2CheckBox                     matlab.ui.control.CheckBox
        Rx3CheckBox                     matlab.ui.control.CheckBox
        Rx1CheckBox                     matlab.ui.control.CheckBox
        Rx0CheckBox                     matlab.ui.control.CheckBox
        ConfigureRadar1Button           matlab.ui.control.Button
        PeriodicitymsEditField          matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel     matlab.ui.control.Label
        NoofChirpLoopsEditField         matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel    matlab.ui.control.Label
        NoofFramesEditField             matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel        matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        SampleRatekspsEditField         matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel    matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        StartFreqGHzEditField           matlab.ui.control.NumericEditField
        StartFreqGHzEditFieldLabel      matlab.ui.control.Label
        ScannerSetupTab                 matlab.ui.container.Tab
        StartScanPanel                  matlab.ui.container.Panel
        StartScanButton                 matlab.ui.control.Button
        SingleMovementPanel             matlab.ui.container.Panel
        HomeButton                      matlab.ui.control.Button
        YStepSizemmEditField_2          matlab.ui.control.NumericEditField
        YStepSizemmEditField_2Label     matlab.ui.control.Label
        XStepSizemmEditField_2          matlab.ui.control.NumericEditField
        XStepSizemmEditField_2Label_2   matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        ConfigureScannerPanel           matlab.ui.container.Panel
        YArraySizemmEditField           matlab.ui.control.NumericEditField
        YArraySizemmEditFieldLabel      matlab.ui.control.Label
        XArraySizemmEditField           matlab.ui.control.NumericEditField
        XArraySizemmEditFieldLabel      matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        ConfigureScanButton             matlab.ui.control.Button
        ScanTimeminEditField            matlab.ui.control.NumericEditField
        ScanTimeminEditFieldLabel       matlab.ui.control.Label
        YScanSizemmEditField            matlab.ui.control.NumericEditField
        YScanSizemmEditFieldLabel       matlab.ui.control.Label
        XScanSizemmEditField            matlab.ui.control.NumericEditField
        XScanSizemmEditFieldLabel       matlab.ui.control.Label
        ConfigureSynchronizerPanel      matlab.ui.container.Panel
        Radar2CheckBox                  matlab.ui.control.CheckBox
        Radar1CheckBox                  matlab.ui.control.CheckBox
        DeltaXmmEditField               matlab.ui.control.NumericEditField
        XSpeedmmsEditFieldLabel_4       matlab.ui.control.Label
        NumYStepsEditField              matlab.ui.control.NumericEditField
        XSpeedmmsEditFieldLabel_3       matlab.ui.control.Label
        NumXStepsEditField              matlab.ui.control.NumericEditField
        NumXStepsEditFieldLabel         matlab.ui.control.Label
        XOffsetmmEditField              matlab.ui.control.NumericEditField
        XOffsetmmEditFieldLabel         matlab.ui.control.Label
        ConfigureSynchronizerButton     matlab.ui.control.Button
        ConnecttoSynchronizerPanel      matlab.ui.container.Panel
        ConnectSynchronizerButton       matlab.ui.control.Button
        ConfigureMotionControllerPanel  matlab.ui.container.Panel
        TwoDirectionScanningCheckBox    matlab.ui.control.CheckBox
        XStepSizemmEditField            matlab.ui.control.NumericEditField
        XStepSizemmEditField_2Label     matlab.ui.control.Label
        YStepSizemmEditField            matlab.ui.control.NumericEditField
        YStepSizemmEditFieldLabel       matlab.ui.control.Label
        YSpeedmmsEditField              matlab.ui.control.NumericEditField
        XSpeedmmsEditFieldLabel_2       matlab.ui.control.Label
        XSpeedmmsEditField              matlab.ui.control.NumericEditField
        XSpeedmmsEditFieldLabel         matlab.ui.control.Label
        PulsesrevEditField              matlab.ui.control.NumericEditField
        PulsesrevEditFieldLabel         matlab.ui.control.Label
        mmrevEditField                  matlab.ui.control.NumericEditField
        mmrevEditFieldLabel             matlab.ui.control.Label
        ConfigureMotionControllerButton  matlab.ui.control.Button
        ConnecttoMotionControllerPanel  matlab.ui.container.Panel
        ConnectMotionControllerButton   matlab.ui.control.Button
        SelectMotionControllerPanel     matlab.ui.container.Panel
        MotionControllersButtonGroup    matlab.ui.container.ButtonGroup
        AxiDrawButton                   matlab.ui.control.ToggleButton
        AMC4030Button                   matlab.ui.control.ToggleButton
    end

    properties
        mmWavePath = "C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio"

        dca1
        dca2
        radar1
        radar2
        scanner
        mcu
    end

    methods (Access = private)

        function setupRadars(app)
            app.radar1.connectionLamp = app.Radar1ConnectionLamp;
            app.radar1.configurationLamp = app.Radar1ConfigurationLamp;

            app.radar2.connectionLamp = app.Radar2ConnectionLamp;
            app.radar2.configurationLamp = app.Radar2ConfigurationLamp;
        end

        function setupMCU(app)
            app.mcu.connectionLamp = app.MotionControllerConnectionLamp;
            app.mcu.configurationLamp = app.MotionControllerConfigurationLamp;
        end

        function setupDCAs(app)
            app.dca1.prepareLamp = app.DCA1PrepareLamp;
            app.dca2.prepareLamp = app.DCA2PrepareLamp;
        end

        function setupScanner(app)
            app.scanner.method = "Rectilinear";
            app.MotionControllersButtonGroupSelectionChanged();
            
            app.scanner.sync.connectionLamp = app.SynchronizerConnectionLamp;
            app.scanner.sync.configurationLamp = app.SynchronizerConfigurationLamp;
            
            app.scanner.xField = app.XPositionmmEditField;
            app.scanner.yField = app.YPositionmmEditField;
            
            app.scanner.radar1 = app.radar1;
            app.scanner.radar2 = app.radar2;
            
            app.scanner.dca1 = app.dca1;
            app.scanner.dca2 = app.dca2;
        end

        function NumChirpLoops = checkRadarParameters(app,radarObj,NumChirpLoops,ADCSamples)
            % Ensure number of chirp loops is multiple of 4
            if mod(NumChirpLoops,4) ~= 0
                % Reduce to value that is multiple of 4
                NumChirpLoops = NumChirpLoops - mod(NumChirpLoops,4);
            end

            % Ensure memory usage is OK
            if ADCSamples * NumChirpLoops > 16384
                app.MainTextArea.Value = "Out of limits on chirp parameters!";
                radarObj.configurationLamp.Color = 'red';

                cpN = 16384/ADCSamples;
                adcN = 16384/NumChirpLoops;
                app.MainTextArea.Value = "To resolve reduce to " + floor(cpN) + " chirp loops " + " - Reduce to " + floor(adcN) + " ADC samples";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folders to the path
            splitPath = regexp(cd,filesep,'split');
            if string(splitPath{end}) ~= "dual-radar-gui"
                try
                    cd(fileparts(which('dual_radar_gui.mlapp')));
                catch
                end
            end
            
            splitPath = regexp(cd,filesep,'split');
            while string(splitPath{end}) ~= "dual-radar-gui"
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct "dual-radar-gui" folder?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app,0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""dual-radar-gui"" Path");
                cd(directoryname);
                splitPath = regexp(cd,filesep,'split');
            end
            addpath(genpath("./"));

            % Set the mmWaveStudio path
            splitPath = regexp(app.mmWavePath,filesep,'split');
            while string(splitPath{end}) ~= "mmWaveStudio" || ~exist(app.mmWavePath,'dir')
                app.mmWavePath = uigetdir('C:\',"Select mmWaveStudio Installation Path");
                splitPath = regexp(app.mmWavePath,filesep,'split');
            end
            app.MainTextArea.Value = "mmWaveStudio path properly set to " + app.mmWavePath;

            app.dca1 = DCA_Device(app,1);
            app.dca2 = DCA_Device(app,2);
            setupDCAs(app);

            app.radar1 = TI_Radar_Device(app,1);
            app.radar2 = TI_Radar_Device(app,2);
            setupRadars(app);

            app.scanner = SAR_Scanner_Device(app,79);
            setupScanner(app);
        end

        % Button pushed function: ConnectRadar1Button
        function ConnectRadar1ButtonPushed(app, event)
            if ~app.radar1.isConnected
                if app.radar1.SerialConnect(app) == -1
                    return;
                end
                app.ConnectRadar1Button.Text = "Disconnect Radar 1";
                app.ConnectRadar1Button.FontWeight = "bold";
            else
                app.radar1.SerialDisconnect();
                app.ConnectRadar1Button.Text = "Connect Radar 1";
                app.ConnectRadar1Button.FontWeight = "normal";
            end
        end

        % Button pushed function: ConnectRadar2Button
        function ConnectRadar2ButtonPushed(app, event)
            if ~app.radar2.isConnected
                if app.radar2.SerialConnect(app) == -1
                    return;
                end
                app.ConnectRadar2Button.Text = "Disconnect Radar 2";
                app.ConnectRadar2Button.FontWeight = "bold";
            else
                app.radar2.SerialDisconnect();
                app.ConnectRadar2Button.Text = "Connect Radar 2";
                app.ConnectRadar2Button.FontWeight = "normal";
            end
        end

        % Button pushed function: ConfigureRadar1Button
        function ConfigureRadar1ButtonPushed(app, event)
            % Check radar parameters
            app.NoofChirpLoopsEditField.Value = checkRadarParameters(app,app.radar1,app.NoofChirpLoopsEditField.Value,app.ADCSamplesEditField.Value);

            % Set radar1 properties
            app.radar1.f0_GHz = app.StartFreqGHzEditField.Value;
            app.radar1.K = app.FreqSlopeMHzusEditField.Value;
            app.radar1.idleTime_us = app.IdleTimeusEditField.Value;
            app.radar1.txStartTime_us = app.TXStartTimeusEditField.Value;
            app.radar1.adcStartTime_us = app.ADCStartTimeusEditField.Value;
            app.radar1.adcSamples = app.ADCSamplesEditField.Value;
            app.radar1.fS_ksps = app.SampleRatekspsEditField.Value;
            app.radar1.rampEndTime_us = app.RampEndTimeusEditField.Value;
            app.radar1.numFrames = app.NoofFramesEditField.Value;
            app.radar1.numChirps = app.NoofChirpLoopsEditField.Value;
            app.radar1.pri_ms = app.PeriodicitymsEditField.Value;
            app.radar1.nRx = 4;
            app.radar1.nTx = app.Tx0CheckBox.Value + app.Tx1CheckBox.Value + app.Tx2CheckBox.Value;

            app.radar1.Configure();
        end

        % Button pushed function: ConfigureRadar2Button
        function ConfigureRadar2ButtonPushed(app, event)
            % Check radar parameters
            app.NoofChirpLoopsEditField_2.Value = checkRadarParameters(app,app.radar2,app.NoofChirpLoopsEditField_2.Value,app.ADCSamplesEditField_2.Value);

            % Set radar2 properties
            app.radar2.f0_GHz = app.StartFreqGHzEditField_2.Value;
            app.radar2.K = app.FreqSlopeMHzusEditField_2.Value;
            app.radar2.idleTime_us = app.IdleTimeusEditField_2.Value;
            app.radar2.txStartTime_us = app.TXStartTimeusEditField_2.Value;
            app.radar2.adcStartTime_us = app.ADCStartTimeusEditField_2.Value;
            app.radar2.adcSamples = app.ADCSamplesEditField_2.Value;
            app.radar2.fS_ksps = app.SampleRatekspsEditField_2.Value;
            app.radar2.rampEndTime_us = app.RampEndTimeusEditField_2.Value;
            app.radar2.numFrames = app.NoofFramesEditField_2.Value;
            app.radar2.numChirps = app.NoofChirpLoopsEditField_2.Value;
            app.radar2.pri_ms = app.PeriodicitymsEditField_2.Value;
            app.radar2.nRx = 4;
            app.radar2.nTx = app.Tx0CheckBox_2.Value + app.Tx1CheckBox_2.Value;

            app.radar2.Configure();
        end

        % Button pushed function: PrepareDCA1Button
        function PrepareDCA1ButtonPushed(app, event)
            % Set dca1 properties
            app.dca1.systemIPAddress = app.SystemIPAddressEditField.Value;
            app.dca1.DCA1000IPAddress = app.DCAIPAddressEditField.Value;
            app.dca1.configPort = app.ConfigPortEditField.Value;
            app.dca1.dataPort = app.DataPortEditField.Value;
            app.dca1.fileName = app.FileNameEditField.Value;

            app.dca1.Prepare();
        end

        % Button pushed function: PrepareDCA2Button
        function PrepareDCA2ButtonPushed(app, event)
            % Set dca2 properties
            app.dca2.systemIPAddress = app.SystemIPAddressEditField_2.Value;
            app.dca2.DCA1000IPAddress = app.DCAIPAddressEditField_2.Value;
            app.dca2.configPort = app.ConfigPortEditField_2.Value;
            app.dca2.dataPort = app.DataPortEditField_2.Value;
            app.dca2.fileName = app.FileNameEditField.Value;

            app.dca2.Prepare();
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            % Make sure the ADC samples are the same
            app.ADCSamplesEditField_2.Value = app.ADCSamplesEditField.Value;
        end

        % Selection changed function: MotionControllersButtonGroup
        function MotionControllersButtonGroupSelectionChanged(app, event)
            selected = app.MotionControllersButtonGroup.SelectedObject.Text;
            delete(app.scanner.motionController);
            if selected == "AMC4030"
                app.scanner.motionController = AMC4030_Device(app);
                app.scanner.motionController.connectionLamp = app.MotionControllerConnectionLamp;
                app.scanner.motionController.configurationLamp = app.MotionControllerConfigurationLamp;
            elseif selected == "AxiDraw"
                app.scanner.motionController = Drawer_Device(app);
                app.scanner.motionController.connectionLamp = app.MotionControllerConnectionLamp;
                app.scanner.motionController.configurationLamp = app.MotionControllerConfigurationLamp;
            end
        end

        % Button pushed function: ConnectMotionControllerButton
        function ConnectMotionControllerButtonPushed(app, event)
            app.scanner.motionController.SerialConnect(app);
        end

        % Button pushed function: ConfigureMotionControllerButton
        function ConfigureMotionControllerButtonPushed(app, event)
            app.scanner.motionController.hor_speed_mms = app.XSpeedmmsEditField.Value;
            app.scanner.motionController.ver_speed_mms = app.XSpeedmmsEditField.Value;
            app.scanner.motionController.mm_per_rev = app.mmrevEditField.Value;
            app.scanner.motionController.pulses_per_rev = app.PulsesrevEditField.Value;
            app.scanner.motionController.hor_max_mm = app.XArraySizemmEditField.Value;
            app.scanner.motionController.ver_max_mm = app.YArraySizemmEditField.Value;
            
            app.scanner.motionController.Configure();
        end

        % Button pushed function: ConnectSynchronizerButton
        function ConnectSynchronizerButtonPushed(app, event)
            if ~app.scanner.sync.isConnected
                if app.scanner.sync.SerialConnect(app) == -1
                    return;
                end
                app.ConnectSynchronizerButton.Text = "Disconnect Synchronzier";
                app.ConnectSynchronizerButton.FontWeight = "bold";
            else
                app.scanner.sync.SerialDisconnect();
                app.ConnectSynchronizerButton.Text = "Connect Synchronizer";
                app.ConnectSynchronizerButton.FontWeight = "normal";
            end
        end

        % Button pushed function: ConfigureSynchronizerButton
        function ConfigureSynchronizerButtonPushed(app, event)
            if ~app.scanner.sync.isConnected
                app.MainTextArea.Value = "ERROR: connect synchronzier before trying to configure synchronizer";
                app.SynchronizerConfigurationLamp.Color = 'red';
                app.scanner.sync.isConfigured = false;
                return;
            end
            
            app.SynchronizerConfigurationLamp.Color = 'yellow';

            % Configure the synchronizer
            app.scanner.sync.mm_per_rev = app.mmrevEditField.Value;
            app.scanner.sync.pulses_per_rev = app.PulsesrevEditField.Value;
            app.scanner.sync.xStep_mm = app.XStepSizemmEditField.Value;
            app.scanner.sync.xOffset_mm = app.XOffsetmmEditField.Value;
            app.scanner.sync.numX = app.NumXStepsEditField.Value;
            app.scanner.sync.numY = app.NumYStepsEditField.Value;
            app.scanner.sync.radarSelect = app.Radar1CheckBox.Value + 2*app.Radar2CheckBox.Value;
            app.scanner.sync.DeltaX_mm = app.DeltaXmmEditField.Value;

            % Configure the scanner
            app.scanner.numX = app.NumXStepsEditField.Value;
            app.scanner.numY = app.NumYStepsEditField.Value;

            app.scanner.sync.isConfigured = true;

            app.SynchronizerConfigurationLamp.Color = 'green';
        end

        % Callback function
        function DisconnectSynchronizerButtonPushed(app, event)
            app.scanner.sync.SerialDisconnect();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.dca1.Start();
            app.radar1.Start();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.radar1.Stop();
            app.dca1.Stop();
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.dca2.Start();
            app.radar2.Start();
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.radar2.Stop();
            app.dca2.Stop();
        end

        % Button pushed function: ConfigureScanButton
        function ConfigureScanButtonPushed(app, event)
            app.ConfigureMotionControllerButtonPushed(0);
            app.ConfigureSynchronizerButtonPushed(0);
            
            app.scanner.xStep_m = app.XStepSizemmEditField.Value*1e-3;
            app.scanner.yStep_m = app.YStepSizemmEditField.Value*1e-3;
            app.scanner.numX = app.NumXStepsEditField.Value;
            app.scanner.numY = app.NumYStepsEditField.Value;
            app.scanner.xMax_m = app.XArraySizemmEditField.Value*1e-3;
            app.scanner.yMax_m = app.YArraySizemmEditField.Value*1e-3;
            
            app.scanner.radarSelect = app.Radar1CheckBox.Value + 2*app.Radar2CheckBox.Value;
            app.scanner.DeltaX_m = app.DeltaXmmEditField.Value*1e-3;
            app.scanner.xOffset_m = app.XOffsetmmEditField.Value*1e-3;
            
            app.scanner.fileName = app.FileNameEditField.Value;
            
            app.scanner.Configure();
            
            app.XScanSizemmEditField.Value = app.scanner.xSize_m*1e3;
            app.YScanSizemmEditField.Value = app.scanner.ySize_m*1e3;
            app.ScanTimeminEditField.Value = app.scanner.scanTime_min;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            xMove_mm = app.XStepSizemmEditField_2.Value;
            yMove_mm = app.YStepSizemmEditField_2.Value;
            
            app.scanner.SingleCommand(xMove_mm,yMove_mm);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            if app.scanner.motionController.Home_AMC4030(1,1,0) == 1
                app.XPositionmmEditField.Value = 0;
                app.YPositionmmEditField.Value = 0;
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.scanner.sync.SendStop();
            app.scanner.motionController.Stop_All();
            app.scanner.isScanning = false;
        end

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
            app.ConfigureScanButtonPushed(0);
            app.scanner.Start();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.Radar160GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar160GHzConfigurationPanel.Title = '4. Radar 1 (60 GHz) Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.Editable = 'off';
            app.StartFreqGHzEditField.Value = 60;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.Value = 124.996;
            app.IdleTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 64;
            app.SampleRatekspsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.Value = 32;
            app.NoofFramesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField.Editable = 'off';
            app.NoofChirpLoopsEditField.Value = 4;
            app.PeriodicitymsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.Value = 50;
            app.ConfigureRadar1Button = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.ConfigureRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar1ButtonPushed, true);
            app.ConfigureRadar1Button.Text = 'Configure Radar 1';
            app.Rx0CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx0CheckBox.Enable = 'off';
            app.Rx0CheckBox.Text = 'Rx0';
            app.Rx0CheckBox.Value = true;
            app.Rx1CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx1CheckBox.Enable = 'off';
            app.Rx1CheckBox.Text = 'Rx1';
            app.Rx1CheckBox.Value = true;
            app.Rx3CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx3CheckBox.Enable = 'off';
            app.Rx3CheckBox.Text = 'Rx3';
            app.Rx3CheckBox.Value = true;
            app.Rx2CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Rx2CheckBox.Enable = 'off';
            app.Rx2CheckBox.Text = 'Rx2';
            app.Rx2CheckBox.Value = true;
            app.Tx0CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx0CheckBox.Enable = 'off';
            app.Tx0CheckBox.Text = 'Tx0';
            app.Tx0CheckBox.Value = true;
            app.Tx1CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx1CheckBox.Enable = 'off';
            app.Tx1CheckBox.Text = 'Tx1';
            app.Tx2CheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.Tx2CheckBox.Enable = 'off';
            app.Tx2CheckBox.Text = 'Tx2';
            app.Tx2CheckBox.Value = true;
            app.StartButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SerialConnectionsPanel = uipanel(app.RadarSetupTab);
            app.SerialConnectionsPanel.Title = '1. Serial Connections';
            app.ConnectRadar1Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar1ButtonPushed, true);
            app.ConnectRadar1Button.Text = 'Connect Radar 1';
            app.ConnectRadar2Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar2ButtonPushed, true);
            app.ConnectRadar2Button.Text = 'Connect Radar 2';
            app.Radar277GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar277GHzConfigurationPanel.Title = '5. Radar 2 (77 GHz) Configuration';
            app.StartFreqGHzEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.StartFreqGHzEditField_2Label.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField_2.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField_2.Editable = 'off';
            app.StartFreqGHzEditField_2.Value = 77;
            app.FreqSlopeMHzusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.FreqSlopeMHzusEditField_2Label.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField_2.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField_2.Value = 124.996;
            app.IdleTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.IdleTimeusEditField_2Label.Text = 'Idle Time (us)';
            app.IdleTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField_2.Value = 10;
            app.TXStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.TXStartTimeusEditField_2Label.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCStartTimeusEditField_2Label.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCSamplesEditField_2Label.Text = 'ADC Samples';
            app.ADCSamplesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField_2.Limits = [0 Inf];
            app.ADCSamplesEditField_2.RoundFractionalValues = 'on';
            app.ADCSamplesEditField_2.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField_2.Editable = 'off';
            app.ADCSamplesEditField_2.Value = 64;
            app.SampleRatekspsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.SampleRatekspsEditField_2Label.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField_2.Limits = [2000 37500];
            app.SampleRatekspsEditField_2.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField_2.Value = 2000;
            app.RampEndTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.RampEndTimeusEditField_2Label.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField_2.Value = 32;
            app.NoofFramesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofFramesEditField_2Label.Text = 'No of Frames';
            app.NoofFramesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField_2.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofChirpLoopsEditField_2Label.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField_2.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField_2.Editable = 'off';
            app.NoofChirpLoopsEditField_2.Value = 4;
            app.PeriodicitymsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.PeriodicitymsEditField_2Label.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField_2.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField_2.Value = 50;
            app.ConfigureRadar2Button = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.ConfigureRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar2ButtonPushed, true);
            app.ConfigureRadar2Button.Text = 'Configure Radar 2';
            app.Rx0CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx0CheckBox_2.Enable = 'off';
            app.Rx0CheckBox_2.Text = 'Rx0';
            app.Rx0CheckBox_2.Value = true;
            app.Rx1CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx1CheckBox_2.Enable = 'off';
            app.Rx1CheckBox_2.Text = 'Rx1';
            app.Rx1CheckBox_2.Value = true;
            app.Rx3CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx3CheckBox_2.Enable = 'off';
            app.Rx3CheckBox_2.Text = 'Rx3';
            app.Rx3CheckBox_2.Value = true;
            app.Rx2CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Rx2CheckBox_2.Enable = 'off';
            app.Rx2CheckBox_2.Text = 'Rx2';
            app.Rx2CheckBox_2.Value = true;
            app.Tx0CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Tx0CheckBox_2.Enable = 'off';
            app.Tx0CheckBox_2.Text = 'Tx0';
            app.Tx0CheckBox_2.Value = true;
            app.Tx1CheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.Tx1CheckBox_2.Enable = 'off';
            app.Tx1CheckBox_2.Text = 'Tx1';
            app.Tx1CheckBox_2.Value = true;
            app.StartButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.DCA1ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA1ConfigurationPanel.Title = '2. DCA 1 Configuration';
            app.SystemIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.SystemIPAddressEditFieldLabel.Text = 'System IP Address';
            app.SystemIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.SystemIPAddressEditField.Value = '192.168.33.30';
            app.DCAIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DCAIPAddressEditFieldLabel.Text = 'DCA IP Address';
            app.DCAIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DCAIPAddressEditField.Value = '192.168.33.180';
            app.ConfigPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.ConfigPortEditFieldLabel.Text = 'Config Port';
            app.ConfigPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.ConfigPortEditField.Value = '4096';
            app.DataPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DataPortEditFieldLabel.Text = 'Data Port';
            app.DataPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DataPortEditField.Value = '4098';
            app.PrepareDCA1Button = uibutton(app.DCA1ConfigurationPanel, 'push');
            app.PrepareDCA1Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA1ButtonPushed, true);
            app.PrepareDCA1Button.Text = 'Prepare DCA 1';
            app.DCA1PrepareLamp = uilamp(app.DCA1ConfigurationPanel);
            app.DCA2ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA2ConfigurationPanel.Title = '3. DCA 2 Configuration';
            app.SystemIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.SystemIPAddressEditField_2Label.Text = 'System IP Address';
            app.SystemIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.SystemIPAddressEditField_2.Value = '192.168.44.40';
            app.DCAIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DCAIPAddressEditField_2Label.Text = 'DCA IP Address';
            app.DCAIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DCAIPAddressEditField_2.Value = '192.168.44.180';
            app.ConfigPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.ConfigPortEditField_2Label.Text = 'Config Port';
            app.ConfigPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.ConfigPortEditField_2.Value = '4092';
            app.DataPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DataPortEditField_2Label.Text = 'Data Port';
            app.DataPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DataPortEditField_2.Value = '4094';
            app.PrepareDCA2Button = uibutton(app.DCA2ConfigurationPanel, 'push');
            app.PrepareDCA2Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA2ButtonPushed, true);
            app.PrepareDCA2Button.Text = 'Prepare DCA 2';
            app.DCA2PrepareLamp = uilamp(app.DCA2ConfigurationPanel);
            app.ScannerSetupTab = uitab(app.TabGroup);
            app.ScannerSetupTab.Title = 'Scanner Setup';
            app.SelectMotionControllerPanel = uipanel(app.ScannerSetupTab);
            app.SelectMotionControllerPanel.Title = '1. Select Motion Controller';
            app.MotionControllersButtonGroup = uibuttongroup(app.SelectMotionControllerPanel);
            app.MotionControllersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotionControllersButtonGroupSelectionChanged, true);
            app.MotionControllersButtonGroup.Title = 'Motion Controllers';
            app.AMC4030Button = uitogglebutton(app.MotionControllersButtonGroup);
            app.AMC4030Button.Text = 'AMC4030';
            app.AMC4030Button.Value = true;
            app.AxiDrawButton = uitogglebutton(app.MotionControllersButtonGroup);
            app.AxiDrawButton.Text = 'AxiDraw';
            app.ConnecttoMotionControllerPanel = uipanel(app.ScannerSetupTab);
            app.ConnecttoMotionControllerPanel.Title = '2. Connect to Motion Controller';
            app.ConnectMotionControllerButton = uibutton(app.ConnecttoMotionControllerPanel, 'push');
            app.ConnectMotionControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMotionControllerButtonPushed, true);
            app.ConnectMotionControllerButton.Text = 'Connect Motion Controller';
            app.ConfigureMotionControllerPanel = uipanel(app.ScannerSetupTab);
            app.ConfigureMotionControllerPanel.Title = '3. Configure Motion Controller';
            app.ConfigureMotionControllerButton = uibutton(app.ConfigureMotionControllerPanel, 'push');
            app.ConfigureMotionControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureMotionControllerButtonPushed, true);
            app.ConfigureMotionControllerButton.Text = 'Configure Motion Controller';
            app.mmrevEditFieldLabel = uilabel(app.ConfigureMotionControllerPanel);
            app.mmrevEditFieldLabel.Text = 'mm / rev';
            app.mmrevEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.mmrevEditField.Limits = [0 Inf];
            app.mmrevEditField.RoundFractionalValues = 'on';
            app.mmrevEditField.ValueDisplayFormat = '%d';
            app.mmrevEditField.Value = 72;
            app.PulsesrevEditFieldLabel = uilabel(app.ConfigureMotionControllerPanel);
            app.PulsesrevEditFieldLabel.Text = 'Pulses / rev';
            app.PulsesrevEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.PulsesrevEditField.Limits = [0 Inf];
            app.PulsesrevEditField.ValueDisplayFormat = '%d';
            app.PulsesrevEditField.Value = 51200;
            app.XSpeedmmsEditFieldLabel = uilabel(app.ConfigureMotionControllerPanel);
            app.XSpeedmmsEditFieldLabel.Text = 'X Speed (mm/s)';
            app.XSpeedmmsEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.XSpeedmmsEditField.Limits = [0 Inf];
            app.XSpeedmmsEditField.ValueDisplayFormat = '%11.3f';
            app.XSpeedmmsEditField.Value = 50;
            app.XSpeedmmsEditFieldLabel_2 = uilabel(app.ConfigureMotionControllerPanel);
            app.XSpeedmmsEditFieldLabel_2.Text = 'Y Speed (mm/s)';
            app.YSpeedmmsEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.YSpeedmmsEditField.Limits = [0 Inf];
            app.YSpeedmmsEditField.ValueDisplayFormat = '%11.3f';
            app.YSpeedmmsEditField.Value = 50;
            app.YStepSizemmEditFieldLabel = uilabel(app.ConfigureMotionControllerPanel);
            app.YStepSizemmEditFieldLabel.Text = 'Y-Step Size (mm)';
            app.YStepSizemmEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.YStepSizemmEditField.Limits = [0 Inf];
            app.YStepSizemmEditField.ValueDisplayFormat = '%11.7f';
            app.YStepSizemmEditField.Value = 7.589682481013;
            app.XStepSizemmEditField_2Label = uilabel(app.ConfigureMotionControllerPanel);
            app.XStepSizemmEditField_2Label.Text = 'X-Step Size (mm)';
            app.XStepSizemmEditField = uieditfield(app.ConfigureMotionControllerPanel, 'numeric');
            app.XStepSizemmEditField.Limits = [0 Inf];
            app.XStepSizemmEditField.ValueDisplayFormat = '%11.7f';
            app.XStepSizemmEditField.Value = 0.948710310126582;
            app.TwoDirectionScanningCheckBox = uicheckbox(app.ConfigureMotionControllerPanel);
            app.TwoDirectionScanningCheckBox.Enable = 'off';
            app.TwoDirectionScanningCheckBox.Text = 'Two-Direction Scanning';
            app.TwoDirectionScanningCheckBox.Value = true;
            app.ConnecttoSynchronizerPanel = uipanel(app.ScannerSetupTab);
            app.ConnecttoSynchronizerPanel.Title = '4. Connect to Synchronizer';
            app.ConnectSynchronizerButton = uibutton(app.ConnecttoSynchronizerPanel, 'push');
            app.ConnectSynchronizerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSynchronizerButtonPushed, true);
            app.ConnectSynchronizerButton.Text = 'Connect Synchronizer';
            app.ConfigureSynchronizerPanel = uipanel(app.ScannerSetupTab);
            app.ConfigureSynchronizerPanel.Title = '5. Configure Synchronizer';
            app.ConfigureSynchronizerButton = uibutton(app.ConfigureSynchronizerPanel, 'push');
            app.ConfigureSynchronizerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureSynchronizerButtonPushed, true);
            app.ConfigureSynchronizerButton.Text = 'Configure Synchronizer';
            app.XOffsetmmEditFieldLabel = uilabel(app.ConfigureSynchronizerPanel);
            app.XOffsetmmEditFieldLabel.Text = 'X-Offset (mm)';
            app.XOffsetmmEditField = uieditfield(app.ConfigureSynchronizerPanel, 'numeric');
            app.XOffsetmmEditField.Limits = [0 Inf];
            app.XOffsetmmEditField.ValueDisplayFormat = '%11.3f';
            app.NumXStepsEditFieldLabel = uilabel(app.ConfigureSynchronizerPanel);
            app.NumXStepsEditFieldLabel.Text = 'Num X-Steps';
            app.NumXStepsEditField = uieditfield(app.ConfigureSynchronizerPanel, 'numeric');
            app.NumXStepsEditField.Limits = [0 Inf];
            app.NumXStepsEditField.RoundFractionalValues = 'on';
            app.NumXStepsEditField.ValueDisplayFormat = '%d';
            app.NumXStepsEditField.Value = 256;
            app.XSpeedmmsEditFieldLabel_3 = uilabel(app.ConfigureSynchronizerPanel);
            app.XSpeedmmsEditFieldLabel_3.Text = 'Num Y-Steps';
            app.NumYStepsEditField = uieditfield(app.ConfigureSynchronizerPanel, 'numeric');
            app.NumYStepsEditField.Limits = [0 Inf];
            app.NumYStepsEditField.RoundFractionalValues = 'on';
            app.NumYStepsEditField.ValueDisplayFormat = '%d';
            app.NumYStepsEditField.Value = 32;
            app.XSpeedmmsEditFieldLabel_4 = uilabel(app.ConfigureSynchronizerPanel);
            app.XSpeedmmsEditFieldLabel_4.Text = 'ΔX (mm)';
            app.DeltaXmmEditField = uieditfield(app.ConfigureSynchronizerPanel, 'numeric');
            app.DeltaXmmEditField.Limits = [0 Inf];
            app.DeltaXmmEditField.RoundFractionalValues = 'on';
            app.DeltaXmmEditField.ValueDisplayFormat = '%11.3f';
            app.DeltaXmmEditField.Value = 20;
            app.Radar1CheckBox = uicheckbox(app.ConfigureSynchronizerPanel);
            app.Radar1CheckBox.Text = 'Radar 1';
            app.Radar2CheckBox = uicheckbox(app.ConfigureSynchronizerPanel);
            app.Radar2CheckBox.Text = 'Radar 2';
            app.ConfigureScannerPanel = uipanel(app.ScannerSetupTab);
            app.ConfigureScannerPanel.Title = '6. Configure Scanner';
            app.XScanSizemmEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.XScanSizemmEditFieldLabel.Text = 'X-Scan Size (mm)';
            app.XScanSizemmEditField = uieditfield(app.ConfigureScannerPanel, 'numeric');
            app.XScanSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.XScanSizemmEditField.Editable = 'off';
            app.YScanSizemmEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.YScanSizemmEditFieldLabel.Text = 'Y-Scan Size (mm)';
            app.YScanSizemmEditField = uieditfield(app.ConfigureScannerPanel, 'numeric');
            app.YScanSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.YScanSizemmEditField.Editable = 'off';
            app.ScanTimeminEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.ScanTimeminEditFieldLabel.Text = 'Scan Time (min)';
            app.ScanTimeminEditField = uieditfield(app.ConfigureScannerPanel, 'numeric');
            app.ScanTimeminEditField.ValueDisplayFormat = '%11.3f';
            app.ScanTimeminEditField.Editable = 'off';
            app.ConfigureScanButton = uibutton(app.ConfigureScannerPanel, 'push');
            app.ConfigureScanButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureScanButtonPushed, true);
            app.ConfigureScanButton.Text = 'Configure Scan';
            app.FileNameEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ConfigureScannerPanel, 'text');
            app.FileNameEditField.Value = 'scan0';
            app.XArraySizemmEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.XArraySizemmEditFieldLabel.Text = 'X-Array Size (mm)';
            app.XArraySizemmEditField = uieditfield(app.ConfigureScannerPanel, 'numeric');
            app.XArraySizemmEditField.Limits = [0 Inf];
            app.XArraySizemmEditField.ValueDisplayFormat = '%11.3f';
            app.XArraySizemmEditField.Value = 500;
            app.YArraySizemmEditFieldLabel = uilabel(app.ConfigureScannerPanel);
            app.YArraySizemmEditFieldLabel.Text = 'Y-Array Size (mm)';
            app.YArraySizemmEditField = uieditfield(app.ConfigureScannerPanel, 'numeric');
            app.YArraySizemmEditField.Limits = [0 Inf];
            app.YArraySizemmEditField.ValueDisplayFormat = '%11.3f';
            app.YArraySizemmEditField.Value = 500;
            app.SingleMovementPanel = uipanel(app.ScannerSetupTab);
            app.SingleMovementPanel.Title = 'Single Movement';
            app.SendButton = uibutton(app.SingleMovementPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.XStepSizemmEditField_2Label_2 = uilabel(app.SingleMovementPanel);
            app.XStepSizemmEditField_2Label_2.Text = 'X-Step Size (mm)';
            app.XStepSizemmEditField_2 = uieditfield(app.SingleMovementPanel, 'numeric');
            app.XStepSizemmEditField_2.ValueDisplayFormat = '%11.3f';
            app.YStepSizemmEditField_2Label = uilabel(app.SingleMovementPanel);
            app.YStepSizemmEditField_2Label.Text = 'Y-Step Size (mm)';
            app.YStepSizemmEditField_2 = uieditfield(app.SingleMovementPanel, 'numeric');
            app.YStepSizemmEditField_2.RoundFractionalValues = 'on';
            app.YStepSizemmEditField_2.ValueDisplayFormat = '%11.3f';
            app.HomeButton = uibutton(app.SingleMovementPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.StartScanPanel = uipanel(app.ScannerSetupTab);
            app.StartScanPanel.Title = '7. Start Scan';
            app.StartScanButton = uibutton(app.StartScanPanel, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.DualRadarSARControllerLabel = uilabel(app.UIFigure);
            app.DualRadarSARControllerLabel.Text = 'Dual Radar SAR Controller';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel = uilabel(app.UIFigure);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel.Text = {'Author: Josiah W. Smith'; 'Advisor: Murat Torlak'};
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.MotionControllerConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar2ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar1ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.MotionControllerConnectionLabel = uilabel(app.ConnectionsPanel);
            app.MotionControllerConnectionLabel.Text = 'Motion Controller Connection';
            app.Radar2ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar2ConnectionLabel.Text = 'Radar 2 Connection';
            app.Radar1ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar1ConnectionLabel.Text = 'Radar 1 Connection';
            app.SynchronizerConnectionLabel = uilabel(app.ConnectionsPanel);
            app.SynchronizerConnectionLabel.Text = 'Synchronizer Connection';
            app.SynchronizerConnectionLamp = uilamp(app.ConnectionsPanel);
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.MotionControllerConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar2ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar1ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MotionControllerConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.MotionControllerConfigurationLabel.Text = 'Motion Controller Configuration';
            app.Radar2ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar2ConfigurationLabel.Text = 'Radar 2 Configuration';
            app.Radar1ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar1ConfigurationLabel.Text = 'Radar 1 Configuration';
            app.SynchronizerConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.SynchronizerConfigurationLabel.Text = 'Synchronizer Configuration';
            app.SynchronizerConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MainTextArea = uitextarea(app.UIFigure);
            app.ADCStartTimeusEditField_3Label = uilabel(app.UIFigure);
            app.ADCStartTimeusEditField_3Label.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ADCStartTimeusEditField_3.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditField_3Label = uilabel(app.UIFigure);
            app.ADCSamplesEditField_3Label.Text = 'ADC Samples';
            app.ADCSamplesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ADCSamplesEditField_3.Limits = [0 Inf];
            app.ADCSamplesEditField_3.RoundFractionalValues = 'on';
            app.ADCSamplesEditField_3.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField_3.Value = 64;
            app.SampleRatekspsEditField_3Label = uilabel(app.UIFigure);
            app.SampleRatekspsEditField_3Label.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SampleRatekspsEditField_3.Limits = [2000 37500];
            app.SampleRatekspsEditField_3.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField_3.Value = 2000;
            app.RampEndTimeusEditField_3Label = uilabel(app.UIFigure);
            app.RampEndTimeusEditField_3Label.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RampEndTimeusEditField_3.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField_3.Value = 32;
            app.NoofFramesEditField_3Label = uilabel(app.UIFigure);
            app.NoofFramesEditField_3Label.Text = 'No of Frames';
            app.NoofFramesEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NoofFramesEditField_3.ValueDisplayFormat = '%11.3g';
            app.XPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.XPositionmmEditFieldLabel.Text = 'X-Position (mm)';
            app.XPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.XPositionmmEditField.Editable = 'off';
            app.PeriodicitymsEditField_3Label = uilabel(app.UIFigure);
            app.PeriodicitymsEditField_3Label.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PeriodicitymsEditField_3.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField_3.Value = 50;
            app.YPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.YPositionmmEditFieldLabel.Text = 'Y-Position (mm)';
            app.YPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.YPositionmmEditField.Editable = 'off';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dual_radar_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end