classdef dual_radar_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HelpMenu                        matlab.ui.container.Menu
        WISLABWebsiteMenu               matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        OpenDualRadarBaseGUIMenu        matlab.ui.container.Menu
        DocsMenu                        matlab.ui.container.Menu
        UserGuideMenu                   matlab.ui.container.Menu
        ChangeDCA1000EVMIPAddressMenu   matlab.ui.container.Menu
        IWR1642BOOSTHWTriggerMenu       matlab.ui.container.Menu
        IWR6843ISKHWTriggerMenu         matlab.ui.container.Menu
        EmergencyStopButton             matlab.ui.control.Button
        YPositionmmEditField            matlab.ui.control.NumericEditField
        YPositionmmEditFieldLabel       matlab.ui.control.Label
        XPositionmmEditField            matlab.ui.control.NumericEditField
        XPositionmmEditFieldLabel       matlab.ui.control.Label
        MainTextArea                    matlab.ui.control.TextArea
        ConfigurationPanel              matlab.ui.container.Panel
        ESP32ConfigurationLamp          matlab.ui.control.Lamp
        ESP32ConfigurationLabel         matlab.ui.control.Label
        Radar1ConfigurationLabel        matlab.ui.control.Label
        Radar2ConfigurationLabel        matlab.ui.control.Label
        AMC4030ConfigurationLabel       matlab.ui.control.Label
        Radar1ConfigurationLamp         matlab.ui.control.Lamp
        Radar2ConfigurationLamp         matlab.ui.control.Lamp
        AMC4030ConfigurationLamp        matlab.ui.control.Lamp
        ConnectionsPanel                matlab.ui.container.Panel
        ESP32COMEditField               matlab.ui.control.EditField
        ESP32EditFieldLabel             matlab.ui.control.Label
        AMC4030COMEditField             matlab.ui.control.EditField
        AMC4030EditFieldLabel           matlab.ui.control.Label
        Radar2COMEditField              matlab.ui.control.EditField
        Radar2EditFieldLabel            matlab.ui.control.Label
        Radar1COMEditField              matlab.ui.control.EditField
        Radar1EditFieldLabel            matlab.ui.control.Label
        ESP32ConnectionLamp             matlab.ui.control.Lamp
        ESP32ConnectionLabel            matlab.ui.control.Label
        Radar1ConnectionLabel           matlab.ui.control.Label
        Radar2ConnectionLabel           matlab.ui.control.Label
        AMC4030ConnectionLabel          matlab.ui.control.Label
        Radar1ConnectionLamp            matlab.ui.control.Lamp
        Radar2ConnectionLamp            matlab.ui.control.Lamp
        AMC4030ConnectionLamp           matlab.ui.control.Lamp
        AuthorJosiahWSmithAdvisorMuratTorlakLabel  matlab.ui.control.Label
        DualRadarSARControllerLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RadarSetupTab                   matlab.ui.container.Tab
        DCA2ConfigurationPanel          matlab.ui.container.Panel
        DCA2TemplateDropDown            matlab.ui.control.DropDown
        DCA2PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA2Button               matlab.ui.control.Button
        DataPortEditField_2             matlab.ui.control.EditField
        DataPortEditField_2Label        matlab.ui.control.Label
        ConfigPortEditField_2           matlab.ui.control.EditField
        ConfigPortEditField_2Label      matlab.ui.control.Label
        DCAIPAddressEditField_2         matlab.ui.control.EditField
        DCAIPAddressEditField_2Label    matlab.ui.control.Label
        SystemIPAddressEditField_2      matlab.ui.control.EditField
        SystemIPAddressEditField_2Label  matlab.ui.control.Label
        DCA1ConfigurationPanel          matlab.ui.container.Panel
        DCA1TemplateDropDown            matlab.ui.control.DropDown
        DCA1PrepareLamp                 matlab.ui.control.Lamp
        PrepareDCA1Button               matlab.ui.control.Button
        DataPortEditField               matlab.ui.control.EditField
        DataPortEditFieldLabel          matlab.ui.control.Label
        ConfigPortEditField             matlab.ui.control.EditField
        ConfigPortEditFieldLabel        matlab.ui.control.Label
        DCAIPAddressEditField           matlab.ui.control.EditField
        DCAIPAddressEditFieldLabel      matlab.ui.control.Label
        SystemIPAddressEditField        matlab.ui.control.EditField
        SystemIPAddressEditFieldLabel   matlab.ui.control.Label
        Radar277GHzConfigurationPanel   matlab.ui.container.Panel
        SerialNumberEditField_2         matlab.ui.control.NumericEditField
        SerialNumberEditField_2Label    matlab.ui.control.Label
        HardwareTriggerCheckBox_2       matlab.ui.control.CheckBox
        CalibrateButton_2               matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        StartButton_2                   matlab.ui.control.Button
        ConfigureRadar2Button           matlab.ui.control.Button
        PeriodicitymsEditField_2        matlab.ui.control.NumericEditField
        PeriodicitymsEditField_2Label   matlab.ui.control.Label
        NoofChirpLoopsEditField_2       matlab.ui.control.NumericEditField
        NoofChirpLoopsEditField_2Label  matlab.ui.control.Label
        NoofFramesEditField_2           matlab.ui.control.NumericEditField
        NoofFramesEditField_2Label      matlab.ui.control.Label
        RampEndTimeusEditField_2        matlab.ui.control.NumericEditField
        RampEndTimeusEditField_2Label   matlab.ui.control.Label
        SampleRatekspsEditField_2       matlab.ui.control.NumericEditField
        SampleRatekspsEditField_2Label  matlab.ui.control.Label
        ADCSamplesEditField_2           matlab.ui.control.NumericEditField
        ADCSamplesEditField_2Label      matlab.ui.control.Label
        ADCStartTimeusEditField_2       matlab.ui.control.NumericEditField
        ADCStartTimeusEditField_2Label  matlab.ui.control.Label
        TXStartTimeusEditField_2        matlab.ui.control.NumericEditField
        TXStartTimeusEditField_2Label   matlab.ui.control.Label
        IdleTimeusEditField_2           matlab.ui.control.NumericEditField
        IdleTimeusEditField_2Label      matlab.ui.control.Label
        FreqSlopeMHzusEditField_2       matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditField_2Label  matlab.ui.control.Label
        StartFreqGHzEditField_2         matlab.ui.control.NumericEditField
        StartFreqGHzEditField_2Label    matlab.ui.control.Label
        SerialConnectionsPanel          matlab.ui.container.Panel
        ConnectRadar2Button             matlab.ui.control.Button
        ConnectRadar1Button             matlab.ui.control.Button
        Radar160GHzConfigurationPanel   matlab.ui.container.Panel
        SerialNumberEditField           matlab.ui.control.NumericEditField
        PeriodicitymsEditField_3Label_2  matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        HardwareTriggerCheckBox         matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ConfigureRadar1Button           matlab.ui.control.Button
        PeriodicitymsEditField          matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel     matlab.ui.control.Label
        NoofChirpLoopsEditField         matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel    matlab.ui.control.Label
        NoofFramesEditField             matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel        matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        SampleRatekspsEditField         matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel    matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        StartFreqGHzEditField           matlab.ui.control.NumericEditField
        StartFreqGHzEditFieldLabel      matlab.ui.control.Label
        ScannerSetupTab                 matlab.ui.container.Tab
        ScanNotesEditField              matlab.ui.control.EditField
        ScanNotesEditFieldLabel         matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        LoadDataButton                  matlab.ui.control.Button
        StartScanPanel                  matlab.ui.container.Panel
        StartScanButton                 matlab.ui.control.Button
        SingleMovementPanel             matlab.ui.container.Panel
        HomeButton                      matlab.ui.control.Button
        YStepSizemmEditField_2          matlab.ui.control.NumericEditField
        YStepSizemmEditField_2Label     matlab.ui.control.Label
        XStepSizemmEditField_2          matlab.ui.control.NumericEditField
        XStepSizemmEditField_2Label_2   matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        ConfigureScanPanel              matlab.ui.container.Panel
        ScannerPeriodicitymsEditField   matlab.ui.control.NumericEditField
        PeriodicitymsEditField_3Label   matlab.ui.control.Label
        DeltaXmmEditField               matlab.ui.control.NumericEditField
        XmmEditFieldLabel               matlab.ui.control.Label
        TwoDirectionScanningCheckBox    matlab.ui.control.CheckBox
        Radar2CheckBox                  matlab.ui.control.CheckBox
        Radar1CheckBox                  matlab.ui.control.CheckBox
        NumYStepsEditField              matlab.ui.control.NumericEditField
        NumYStepsEditFieldLabel         matlab.ui.control.Label
        NumXStepsEditField              matlab.ui.control.NumericEditField
        NumXStepsEditFieldLabel         matlab.ui.control.Label
        XOffsetmmEditField              matlab.ui.control.NumericEditField
        XOffsetmmEditFieldLabel         matlab.ui.control.Label
        XStepSizemmEditField            matlab.ui.control.NumericEditField
        XStepSizemmEditField_2Label     matlab.ui.control.Label
        YStepSizemmEditField            matlab.ui.control.NumericEditField
        YStepSizemmEditFieldLabel       matlab.ui.control.Label
        YMaxSizemmEditField             matlab.ui.control.NumericEditField
        YMaxSizemmEditFieldLabel        matlab.ui.control.Label
        XMaxSizemmEditField             matlab.ui.control.NumericEditField
        XMaxSizemmEditFieldLabel        matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        ConfigureScanButton             matlab.ui.control.Button
        ScanTimeminEditField            matlab.ui.control.NumericEditField
        ScanTimeminEditFieldLabel       matlab.ui.control.Label
        YScanSizemmEditField            matlab.ui.control.NumericEditField
        YScanSizemmEditFieldLabel       matlab.ui.control.Label
        XScanSizemmEditField            matlab.ui.control.NumericEditField
        XScanSizemmEditFieldLabel       matlab.ui.control.Label
        ConfigureAMC4030Panel           matlab.ui.container.Panel
        HomeOffsetmmEditField           matlab.ui.control.NumericEditField
        HomeOffsetmmEditFieldLabel      matlab.ui.control.Label
        HomeSpeedmmsEditField           matlab.ui.control.NumericEditField
        HomeSpeedmmsEditFieldLabel      matlab.ui.control.Label
        YpulsesrevEditField             matlab.ui.control.NumericEditField
        YpulsesrevEditFieldLabel        matlab.ui.control.Label
        YmmrevEditField                 matlab.ui.control.NumericEditField
        YmmrevEditFieldLabel            matlab.ui.control.Label
        YSpeedmmsEditField              matlab.ui.control.NumericEditField
        YSpeedmmsLabel                  matlab.ui.control.Label
        XSpeedmmsEditField              matlab.ui.control.NumericEditField
        XSpeedmmsLabel                  matlab.ui.control.Label
        XpulsesrevEditField             matlab.ui.control.NumericEditField
        XpulsesrevLabel                 matlab.ui.control.Label
        XmmrevEditField                 matlab.ui.control.NumericEditField
        XmmrevEditFieldLabel            matlab.ui.control.Label
        ConfigureAMC4030Button          matlab.ui.control.Button
        ConnecttoScannerPanel           matlab.ui.container.Panel
        ConnectESP32Button              matlab.ui.control.Button
        ConnectAMC4030Button            matlab.ui.control.Button
    end

    properties
        mmWavePath = "C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio"
        isSkipGuide

        dca1
        dca2
        radar1
        radar2
        scanner
        amc
        esp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folders to the path
            if ~isfile('dual_radar_gui.mlapp')
                try
                    cd(fileparts(which('dual_radar_gui.mlapp')));
                catch
                end
            end

            while ~isfile('dual_radar_gui.mlapp')
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct folder that contains "dual_radar_gui.mlapp"?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app,0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""dual-radar-gui"" Path");
                cd(directoryname);
            end
            addpath(genpath("./"));

            % Set the mmWaveStudio path
            splitPath = regexp(app.mmWavePath,filesep,'split');
            while string(splitPath{end}) ~= "mmWaveStudio" || ~exist(app.mmWavePath,'dir')
                app.mmWavePath = uigetdir('C:\',"Select mmWaveStudio Installation Path (...\mmwave_studio_xx_xx_xx_xx\mmWaveStudio\");
                splitPath = regexp(app.mmWavePath,filesep,'split');
            end
            app.MainTextArea.Value = "mmWaveStudio path properly set to " + app.mmWavePath;
            
            app.isSkipGuide.Value = false;

            startupDRG(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveFields(app)
            delete(app)
        end

        % Button pushed function: ConnectRadar1Button
        function ConnectRadar1ButtonPushed(app, event)
            if ~app.radar1.isConnected
                if app.radar1.SerialConnect() == -1
                    return;
                end
                app.ConnectRadar1Button.Text = "Disconnect Radar 1";
                app.ConnectRadar1Button.FontWeight = "bold";
            else
                app.radar1.SerialDisconnect();
                app.ConnectRadar1Button.Text = "Connect Radar 1";
                app.ConnectRadar1Button.FontWeight = "normal";
            end
        end

        % Button pushed function: ConnectRadar2Button
        function ConnectRadar2ButtonPushed(app, event)
            if ~app.radar2.isConnected
                if app.radar2.SerialConnect() == -1
                    return;
                end
                app.ConnectRadar2Button.Text = "Disconnect Radar 2";
                app.ConnectRadar2Button.FontWeight = "bold";
            else
                app.radar2.SerialDisconnect();
                app.ConnectRadar2Button.Text = "Connect Radar 2";
                app.ConnectRadar2Button.FontWeight = "normal";
            end
        end

        % Button pushed function: ConfigureRadar1Button
        function ConfigureRadar1ButtonPushed(app, event)
            app.radar1.Configure();
        end

        % Button pushed function: ConfigureRadar2Button
        function ConfigureRadar2ButtonPushed(app, event)
            app.radar2.Configure();
        end

        % Button pushed function: PrepareDCA1Button
        function PrepareDCA1ButtonPushed(app, event)
            app.DCA1TemplateDropDownValueChanged(0);
            % Set dca1 properties
            app.dca1.Prepare(false,true);
        end

        % Button pushed function: PrepareDCA2Button
        function PrepareDCA2ButtonPushed(app, event)
            app.DCA2TemplateDropDownValueChanged(0);
            % Set dca2 properties
            app.dca2.Prepare(false,true);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            % Make sure the ADC samples are the same
            app.ADCSamplesEditField_2.Value = app.ADCSamplesEditField.Value;
        end

        % Button pushed function: ConnectAMC4030Button
        function ConnectAMC4030ButtonPushed(app, event)
            app.amc.SerialConnect();
        end

        % Button pushed function: ConfigureAMC4030Button
        function ConfigureAMC4030ButtonPushed(app, event)
            app.amc.Configure();
        end

        % Button pushed function: ConnectESP32Button
        function ConnectESP32ButtonPushed(app, event)
            if ~app.esp.isConnected
                if app.esp.SerialConnect() == -1
                    return;
                end
                app.ConnectESP32Button.Text = "Disconnect ESP32";
                app.ConnectESP32Button.FontWeight = "bold";
            else
                app.esp.SerialDisconnect();
                app.ConnectESP32Button.Text = "Connect ESP32";
                app.ConnectESP32Button.FontWeight = "normal";
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.dca1.Start();
            app.radar1.Start();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.radar1.Stop();
            app.dca1.Stop();
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.radar2.Start();
            app.dca2.Start();
            app.radar2.Start();
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.radar2.Stop();
            app.dca2.Stop();
        end

        % Button pushed function: ConfigureScanButton
        function ConfigureScanButtonPushed(app, event)
            app.scanner.Configure();
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            xMove_mm = app.XStepSizemmEditField_2.Value;
            yMove_mm = app.YStepSizemmEditField_2.Value;

            app.scanner.SingleCommand(xMove_mm,yMove_mm);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            if app.amc.Home_AMC4030(1,1,0) == 1
                app.XPositionmmEditField.Value = 0; 
                app.YPositionmmEditField.Value = 0;
            end
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.esp.SendStop();
            app.amc.Stop_All();
            app.scanner.isScanning = false;
        end

        % Button pushed function: StartScanButton
        function StartScanButtonPushed(app, event)
            app.ConfigureScanButtonPushed(0);
            app.scanner.Start();
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.scanner.Load();
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.radar1.Calibrate();
        end

        % Button pushed function: CalibrateButton_2
        function CalibrateButton_2Pushed(app, event)
            app.radar2.Calibrate();
        end

        % Value changed function: DCA1TemplateDropDown
        function DCA1TemplateDropDownValueChanged(app, event)
            if app.DCA1TemplateDropDown.Value == "30"
                app.SystemIPAddressEditField.Value = "192.168.33.30";
                app.DCAIPAddressEditField.Value = "192.168.33.180";
                app.dca1.MACAddress = "12.34.56.78.90.12";
                app.ConfigPortEditField.Value = "4096";
                app.DataPortEditField.Value = "4098";
                app.DCA1PrepareLamp.Color = "red";
            elseif app.DCA1TemplateDropDown.Value == "40"
                app.SystemIPAddressEditField.Value = "192.168.44.40";
                app.DCAIPAddressEditField.Value = "192.168.44.180";
                app.dca1.MACAddress = "12.34.56.78.90.12";
                app.ConfigPortEditField.Value = "4092";
                app.DataPortEditField.Value = "4094";
                app.DCA1PrepareLamp.Color = "red";
            elseif app.DCA1TemplateDropDown.Value == "50"
                app.SystemIPAddressEditField.Value = "192.168.55.50";
                app.DCAIPAddressEditField.Value = "192.168.55.180";
                app.dca1.MACAddress = "12.34.56.78.90.55";
                app.ConfigPortEditField.Value = "4056";
                app.DataPortEditField.Value = "4058";
                app.DCA1PrepareLamp.Color = "red";
            end
        end

        % Value changed function: DCA2TemplateDropDown
        function DCA2TemplateDropDownValueChanged(app, event)
            if app.DCA2TemplateDropDown.Value == "30"
                app.SystemIPAddressEditField_2.Value = "192.168.33.30";
                app.DCAIPAddressEditField_2.Value = "192.168.33.180";
                app.dca2.MACAddress = "12.34.56.78.90.12";
                app.ConfigPortEditField_2.Value = "4096";
                app.DataPortEditField_2.Value = "4098";
                app.DCA2PrepareLamp.Color = "red";
            elseif app.DCA2TemplateDropDown.Value == "40"
                app.SystemIPAddressEditField_2.Value = "192.168.44.40";
                app.DCAIPAddressEditField_2.Value = "192.168.44.180";
                app.dca2.MACAddress = "12.34.56.78.90.12";
                app.ConfigPortEditField_2.Value = "4092";
                app.DataPortEditField_2.Value = "4094";
                app.DCA2PrepareLamp.Color = "red";
            elseif app.DCA2TemplateDropDown.Value == "50"
                app.SystemIPAddressEditField_2.Value = "192.168.55.50";
                app.DCAIPAddressEditField_2.Value = "192.168.55.180";
                app.dca2.MACAddress = "12.34.56.78.90.55";
                app.ConfigPortEditField_2.Value = "4056";
                app.DataPortEditField_2.Value = "4058";
                app.DCA2PrepareLamp.Color = "red";
            end
        end

        % Menu selected function: WISLABWebsiteMenu
        function WISLABWebsiteMenuSelected(app, event)
            web("https://labs.utdallas.edu/wislab/");
        end

        % Menu selected function: OpenDualRadarBaseGUIMenu
        function OpenDualRadarBaseGUIMenuSelected(app, event)
            system("dual_radar_base_gui.mlapp");
        end

        % Menu selected function: IWR1642BOOSTHWTriggerMenu
        function IWR1642BOOSTHWTriggerMenuSelected(app, event)
            winopen("IWR1642BOOST_HW_Trigger.pdf")
        end

        % Menu selected function: IWR6843ISKHWTriggerMenu
        function IWR6843ISKHWTriggerMenuSelected(app, event)
            winopen("IWR6843ISK_HW_Trigger.pdf")
        end

        % Menu selected function: ChangeDCA1000EVMIPAddressMenu
        function ChangeDCA1000EVMIPAddressMenuSelected(app, event)
            winopen("Change_DCA1000EVM_IP_Address.pdf")
        end

        % Menu selected function: UserGuideMenu
        function UserGuideMenuSelected(app, event)
            winopen("doc/Dual_Radar_GUI_User_Guide.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.WISLABWebsiteMenu = uimenu(app.HelpMenu);
            app.WISLABWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @WISLABWebsiteMenuSelected, true);
            app.WISLABWebsiteMenu.Text = 'WISLAB Website';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.OpenDualRadarBaseGUIMenu = uimenu(app.ToolsMenu);
            app.OpenDualRadarBaseGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDualRadarBaseGUIMenuSelected, true);
            app.OpenDualRadarBaseGUIMenu.Text = 'Open Dual Radar Base GUI';
            app.DocsMenu = uimenu(app.UIFigure);
            app.DocsMenu.Text = 'Docs';
            app.UserGuideMenu = uimenu(app.DocsMenu);
            app.UserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @UserGuideMenuSelected, true);
            app.UserGuideMenu.Text = 'User Guide';
            app.ChangeDCA1000EVMIPAddressMenu = uimenu(app.DocsMenu);
            app.ChangeDCA1000EVMIPAddressMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeDCA1000EVMIPAddressMenuSelected, true);
            app.ChangeDCA1000EVMIPAddressMenu.Text = 'Change DCA1000EVM IP Address';
            app.IWR1642BOOSTHWTriggerMenu = uimenu(app.DocsMenu);
            app.IWR1642BOOSTHWTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @IWR1642BOOSTHWTriggerMenuSelected, true);
            app.IWR1642BOOSTHWTriggerMenu.Text = 'IWR1642BOOST HW Trigger';
            app.IWR6843ISKHWTriggerMenu = uimenu(app.DocsMenu);
            app.IWR6843ISKHWTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @IWR6843ISKHWTriggerMenuSelected, true);
            app.IWR6843ISKHWTriggerMenu.Text = 'IWR6843ISK HW Trigger';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.Radar160GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar160GHzConfigurationPanel.Title = '4. Radar 1 (60 GHz) Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.Value = 60;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.Value = 124.996;
            app.IdleTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 64;
            app.SampleRatekspsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.Limits = [0 Inf];
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.Value = 32;
            app.NoofFramesEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField.Limits = [0 Inf];
            app.NoofFramesEditField.ValueDisplayFormat = '%d';
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.Limits = [0 Inf];
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%d';
            app.NoofChirpLoopsEditField.Editable = 'off';
            app.NoofChirpLoopsEditField.Value = 4;
            app.PeriodicitymsEditFieldLabel = uilabel(app.Radar160GHzConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.Limits = [0 Inf];
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.Value = 1;
            app.ConfigureRadar1Button = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.ConfigureRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar1ButtonPushed, true);
            app.ConfigureRadar1Button.Text = 'Configure Radar 1';
            app.StartButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HardwareTriggerCheckBox = uicheckbox(app.Radar160GHzConfigurationPanel);
            app.HardwareTriggerCheckBox.Text = 'Hardware Trigger';
            app.HardwareTriggerCheckBox.Value = true;
            app.CalibrateButton = uibutton(app.Radar160GHzConfigurationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.PeriodicitymsEditField_3Label_2 = uilabel(app.Radar160GHzConfigurationPanel);
            app.PeriodicitymsEditField_3Label_2.Text = 'Serial Number';
            app.SerialNumberEditField = uieditfield(app.Radar160GHzConfigurationPanel, 'numeric');
            app.SerialNumberEditField.Limits = [0 9999];
            app.SerialNumberEditField.ValueDisplayFormat = '%.4d';
            app.SerialNumberEditField.Value = 685;
            app.SerialConnectionsPanel = uipanel(app.RadarSetupTab);
            app.SerialConnectionsPanel.Title = '1. Serial Connections';
            app.ConnectRadar1Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar1ButtonPushed, true);
            app.ConnectRadar1Button.Text = 'Connect Radar 1';
            app.ConnectRadar2Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar2ButtonPushed, true);
            app.ConnectRadar2Button.Text = 'Connect Radar 2';
            app.Radar277GHzConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar277GHzConfigurationPanel.Title = '5. Radar 2 (77 GHz) Configuration';
            app.StartFreqGHzEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.StartFreqGHzEditField_2Label.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField_2.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField_2.Value = 77;
            app.FreqSlopeMHzusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.FreqSlopeMHzusEditField_2Label.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField_2.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField_2.Value = 124.996;
            app.IdleTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.IdleTimeusEditField_2Label.Text = 'Idle Time (us)';
            app.IdleTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.IdleTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField_2.Value = 10;
            app.TXStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.TXStartTimeusEditField_2Label.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCStartTimeusEditField_2Label.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.ADCSamplesEditField_2Label.Text = 'ADC Samples';
            app.ADCSamplesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.ADCSamplesEditField_2.Limits = [0 Inf];
            app.ADCSamplesEditField_2.RoundFractionalValues = 'on';
            app.ADCSamplesEditField_2.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField_2.Editable = 'off';
            app.ADCSamplesEditField_2.Value = 64;
            app.SampleRatekspsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.SampleRatekspsEditField_2Label.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField_2.Limits = [2000 37500];
            app.SampleRatekspsEditField_2.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField_2.Value = 2000;
            app.RampEndTimeusEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.RampEndTimeusEditField_2Label.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField_2.Limits = [0 Inf];
            app.RampEndTimeusEditField_2.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField_2.Value = 32;
            app.NoofFramesEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofFramesEditField_2Label.Text = 'No of Frames';
            app.NoofFramesEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofFramesEditField_2.Limits = [0 Inf];
            app.NoofFramesEditField_2.ValueDisplayFormat = '%d';
            app.NoofChirpLoopsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.NoofChirpLoopsEditField_2Label.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField_2.Limits = [0 Inf];
            app.NoofChirpLoopsEditField_2.ValueDisplayFormat = '%d';
            app.NoofChirpLoopsEditField_2.Editable = 'off';
            app.NoofChirpLoopsEditField_2.Value = 4;
            app.PeriodicitymsEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.PeriodicitymsEditField_2Label.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField_2.Limits = [0 Inf];
            app.PeriodicitymsEditField_2.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField_2.Value = 1;
            app.ConfigureRadar2Button = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.ConfigureRadar2Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar2ButtonPushed, true);
            app.ConfigureRadar2Button.Text = 'Configure Radar 2';
            app.StartButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.CalibrateButton_2 = uibutton(app.Radar277GHzConfigurationPanel, 'push');
            app.CalibrateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButton_2Pushed, true);
            app.CalibrateButton_2.Text = 'Calibrate';
            app.HardwareTriggerCheckBox_2 = uicheckbox(app.Radar277GHzConfigurationPanel);
            app.HardwareTriggerCheckBox_2.Text = 'Hardware Trigger';
            app.HardwareTriggerCheckBox_2.Value = true;
            app.SerialNumberEditField_2Label = uilabel(app.Radar277GHzConfigurationPanel);
            app.SerialNumberEditField_2Label.Text = 'Serial Number';
            app.SerialNumberEditField_2 = uieditfield(app.Radar277GHzConfigurationPanel, 'numeric');
            app.SerialNumberEditField_2.Limits = [0 9999];
            app.SerialNumberEditField_2.ValueDisplayFormat = '%.4d';
            app.SerialNumberEditField_2.Value = 23;
            app.DCA1ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA1ConfigurationPanel.Title = '2. DCA 1 Configuration';
            app.SystemIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.SystemIPAddressEditFieldLabel.Text = 'System IP Address';
            app.SystemIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.SystemIPAddressEditField.Value = '192.168.33.30';
            app.DCAIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DCAIPAddressEditFieldLabel.Text = 'DCA IP Address';
            app.DCAIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DCAIPAddressEditField.Value = '192.168.33.180';
            app.ConfigPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.ConfigPortEditFieldLabel.Text = 'Config Port';
            app.ConfigPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.ConfigPortEditField.Value = '4096';
            app.DataPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DataPortEditFieldLabel.Text = 'Data Port';
            app.DataPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DataPortEditField.Value = '4098';
            app.PrepareDCA1Button = uibutton(app.DCA1ConfigurationPanel, 'push');
            app.PrepareDCA1Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA1ButtonPushed, true);
            app.PrepareDCA1Button.Text = 'Prepare DCA 1';
            app.DCA1PrepareLamp = uilamp(app.DCA1ConfigurationPanel);
            app.DCA1TemplateDropDown = uidropdown(app.DCA1ConfigurationPanel);
            app.DCA1TemplateDropDown.Items = {'30', '40', '50'};
            app.DCA1TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @DCA1TemplateDropDownValueChanged, true);
            app.DCA1TemplateDropDown.Value = '30';
            app.DCA2ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA2ConfigurationPanel.Title = '3. DCA 2 Configuration';
            app.SystemIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.SystemIPAddressEditField_2Label.Text = 'System IP Address';
            app.SystemIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.SystemIPAddressEditField_2.Value = '192.168.55.50';
            app.DCAIPAddressEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DCAIPAddressEditField_2Label.Text = 'DCA IP Address';
            app.DCAIPAddressEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DCAIPAddressEditField_2.Value = '192.168.55.180';
            app.ConfigPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.ConfigPortEditField_2Label.Text = 'Config Port';
            app.ConfigPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.ConfigPortEditField_2.Value = '4056';
            app.DataPortEditField_2Label = uilabel(app.DCA2ConfigurationPanel);
            app.DataPortEditField_2Label.Text = 'Data Port';
            app.DataPortEditField_2 = uieditfield(app.DCA2ConfigurationPanel, 'text');
            app.DataPortEditField_2.Value = '4058';
            app.PrepareDCA2Button = uibutton(app.DCA2ConfigurationPanel, 'push');
            app.PrepareDCA2Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA2ButtonPushed, true);
            app.PrepareDCA2Button.Text = 'Prepare DCA 2';
            app.DCA2PrepareLamp = uilamp(app.DCA2ConfigurationPanel);
            app.DCA2TemplateDropDown = uidropdown(app.DCA2ConfigurationPanel);
            app.DCA2TemplateDropDown.Items = {'30', '40', '50'};
            app.DCA2TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @DCA2TemplateDropDownValueChanged, true);
            app.DCA2TemplateDropDown.Value = '50';
            app.ScannerSetupTab = uitab(app.TabGroup);
            app.ScannerSetupTab.Title = 'Scanner Setup';
            app.ConnecttoScannerPanel = uipanel(app.ScannerSetupTab);
            app.ConnecttoScannerPanel.Title = '1. Connect to Scanner';
            app.ConnectAMC4030Button = uibutton(app.ConnecttoScannerPanel, 'push');
            app.ConnectAMC4030Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectAMC4030ButtonPushed, true);
            app.ConnectAMC4030Button.Text = 'Connect AMC4030';
            app.ConnectESP32Button = uibutton(app.ConnecttoScannerPanel, 'push');
            app.ConnectESP32Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectESP32ButtonPushed, true);
            app.ConnectESP32Button.Text = 'Connect ESP32';
            app.ConfigureAMC4030Panel = uipanel(app.ScannerSetupTab);
            app.ConfigureAMC4030Panel.Title = '2. Configure AMC4030';
            app.ConfigureAMC4030Button = uibutton(app.ConfigureAMC4030Panel, 'push');
            app.ConfigureAMC4030Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureAMC4030ButtonPushed, true);
            app.ConfigureAMC4030Button.Text = 'Configure AMC4030';
            app.XmmrevEditFieldLabel = uilabel(app.ConfigureAMC4030Panel);
            app.XmmrevEditFieldLabel.Text = 'X-mm / rev';
            app.XmmrevEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.XmmrevEditField.Limits = [0 Inf];
            app.XmmrevEditField.RoundFractionalValues = 'on';
            app.XmmrevEditField.ValueDisplayFormat = '%d';
            app.XmmrevEditField.Value = 110;
            app.XpulsesrevLabel = uilabel(app.ConfigureAMC4030Panel);
            app.XpulsesrevLabel.Text = 'X-pulses / rev';
            app.XpulsesrevEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.XpulsesrevEditField.Limits = [0 Inf];
            app.XpulsesrevEditField.ValueDisplayFormat = '%d';
            app.XpulsesrevEditField.Value = 20000;
            app.XSpeedmmsLabel = uilabel(app.ConfigureAMC4030Panel);
            app.XSpeedmmsLabel.Text = 'X-Speed (mm/s)';
            app.XSpeedmmsEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.XSpeedmmsEditField.Limits = [0 Inf];
            app.XSpeedmmsEditField.ValueDisplayFormat = '%11.3f';
            app.XSpeedmmsEditField.Value = 200;
            app.YSpeedmmsLabel = uilabel(app.ConfigureAMC4030Panel);
            app.YSpeedmmsLabel.Text = 'Y-Speed (mm/s)';
            app.YSpeedmmsEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.YSpeedmmsEditField.Limits = [0 Inf];
            app.YSpeedmmsEditField.ValueDisplayFormat = '%11.3f';
            app.YSpeedmmsEditField.Value = 50;
            app.YmmrevEditFieldLabel = uilabel(app.ConfigureAMC4030Panel);
            app.YmmrevEditFieldLabel.Text = 'Y-mm / rev';
            app.YmmrevEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.YmmrevEditField.Limits = [0 Inf];
            app.YmmrevEditField.RoundFractionalValues = 'on';
            app.YmmrevEditField.ValueDisplayFormat = '%d';
            app.YmmrevEditField.Value = 110;
            app.YpulsesrevEditFieldLabel = uilabel(app.ConfigureAMC4030Panel);
            app.YpulsesrevEditFieldLabel.Text = 'Y-pulses / rev';
            app.YpulsesrevEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.YpulsesrevEditField.Limits = [0 Inf];
            app.YpulsesrevEditField.ValueDisplayFormat = '%d';
            app.YpulsesrevEditField.Value = 20000;
            app.HomeSpeedmmsEditFieldLabel = uilabel(app.ConfigureAMC4030Panel);
            app.HomeSpeedmmsEditFieldLabel.Text = 'Home Speed (mm/s)';
            app.HomeSpeedmmsEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.HomeSpeedmmsEditField.Limits = [0 Inf];
            app.HomeSpeedmmsEditField.ValueDisplayFormat = '%11.3f';
            app.HomeSpeedmmsEditField.Value = 50;
            app.HomeOffsetmmEditFieldLabel = uilabel(app.ConfigureAMC4030Panel);
            app.HomeOffsetmmEditFieldLabel.Text = 'Home Offset (mm)';
            app.HomeOffsetmmEditField = uieditfield(app.ConfigureAMC4030Panel, 'numeric');
            app.HomeOffsetmmEditField.Limits = [0 Inf];
            app.HomeOffsetmmEditField.ValueDisplayFormat = '%11.3f';
            app.ConfigureScanPanel = uipanel(app.ScannerSetupTab);
            app.ConfigureScanPanel.Title = '3. Configure Scan';
            app.XScanSizemmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.XScanSizemmEditFieldLabel.Text = 'X-Scan Size (mm)';
            app.XScanSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.XScanSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.XScanSizemmEditField.Editable = 'off';
            app.YScanSizemmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.YScanSizemmEditFieldLabel.Text = 'Y-Scan Size (mm)';
            app.YScanSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.YScanSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.YScanSizemmEditField.Editable = 'off';
            app.ScanTimeminEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.ScanTimeminEditFieldLabel.Text = 'Scan Time (min)';
            app.ScanTimeminEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.ScanTimeminEditField.ValueDisplayFormat = '%11.3f';
            app.ScanTimeminEditField.Editable = 'off';
            app.ConfigureScanButton = uibutton(app.ConfigureScanPanel, 'push');
            app.ConfigureScanButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureScanButtonPushed, true);
            app.ConfigureScanButton.Text = 'Configure Scan';
            app.FileNameEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ConfigureScanPanel, 'text');
            app.FileNameEditField.Value = 'scan0';
            app.XMaxSizemmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.XMaxSizemmEditFieldLabel.Text = 'X-Max Size (mm)';
            app.XMaxSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.XMaxSizemmEditField.Limits = [0 Inf];
            app.XMaxSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.XMaxSizemmEditField.Value = 1000;
            app.YMaxSizemmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.YMaxSizemmEditFieldLabel.Text = 'Y-Max Size (mm)';
            app.YMaxSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.YMaxSizemmEditField.Limits = [0 Inf];
            app.YMaxSizemmEditField.ValueDisplayFormat = '%11.3f';
            app.YMaxSizemmEditField.Value = 1000;
            app.YStepSizemmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.YStepSizemmEditFieldLabel.Text = 'Y-Step Size (mm)';
            app.YStepSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.YStepSizemmEditField.Limits = [0 Inf];
            app.YStepSizemmEditField.ValueDisplayFormat = '%11.7f';
            app.YStepSizemmEditField.Value = 7.589682481013;
            app.XStepSizemmEditField_2Label = uilabel(app.ConfigureScanPanel);
            app.XStepSizemmEditField_2Label.Text = 'X-Step Size (mm)';
            app.XStepSizemmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.XStepSizemmEditField.Limits = [0 Inf];
            app.XStepSizemmEditField.ValueDisplayFormat = '%11.7f';
            app.XStepSizemmEditField.Value = 0.948710310126582;
            app.XOffsetmmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.XOffsetmmEditFieldLabel.Text = 'X-Offset (mm)';
            app.XOffsetmmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.XOffsetmmEditField.Limits = [0 Inf];
            app.XOffsetmmEditField.ValueDisplayFormat = '%11.3f';
            app.NumXStepsEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.NumXStepsEditFieldLabel.Text = 'Num X-Steps';
            app.NumXStepsEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.NumXStepsEditField.Limits = [0 Inf];
            app.NumXStepsEditField.RoundFractionalValues = 'on';
            app.NumXStepsEditField.ValueDisplayFormat = '%d';
            app.NumXStepsEditField.Value = 256;
            app.NumYStepsEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.NumYStepsEditFieldLabel.Text = 'Num Y-Steps';
            app.NumYStepsEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.NumYStepsEditField.Limits = [0 Inf];
            app.NumYStepsEditField.RoundFractionalValues = 'on';
            app.NumYStepsEditField.ValueDisplayFormat = '%d';
            app.NumYStepsEditField.Value = 32;
            app.Radar1CheckBox = uicheckbox(app.ConfigureScanPanel);
            app.Radar1CheckBox.Text = 'Radar 1';
            app.Radar1CheckBox.Value = true;
            app.Radar2CheckBox = uicheckbox(app.ConfigureScanPanel);
            app.Radar2CheckBox.Text = 'Radar 2';
            app.TwoDirectionScanningCheckBox = uicheckbox(app.ConfigureScanPanel);
            app.TwoDirectionScanningCheckBox.Enable = 'off';
            app.TwoDirectionScanningCheckBox.Text = 'Two-Direction Scanning';
            app.TwoDirectionScanningCheckBox.Value = true;
            app.XmmEditFieldLabel = uilabel(app.ConfigureScanPanel);
            app.XmmEditFieldLabel.Text = 'ΔX (mm)';
            app.DeltaXmmEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.DeltaXmmEditField.Limits = [0 Inf];
            app.DeltaXmmEditField.ValueDisplayFormat = '%11.3f';
            app.DeltaXmmEditField.Value = 188.5;
            app.PeriodicitymsEditField_3Label = uilabel(app.ConfigureScanPanel);
            app.PeriodicitymsEditField_3Label.Text = 'Periodicity (ms)';
            app.ScannerPeriodicitymsEditField = uieditfield(app.ConfigureScanPanel, 'numeric');
            app.ScannerPeriodicitymsEditField.Limits = [0 Inf];
            app.ScannerPeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.ScannerPeriodicitymsEditField.Editable = 'off';
            app.SingleMovementPanel = uipanel(app.ScannerSetupTab);
            app.SingleMovementPanel.Title = 'Single Movement';
            app.SendButton = uibutton(app.SingleMovementPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.XStepSizemmEditField_2Label_2 = uilabel(app.SingleMovementPanel);
            app.XStepSizemmEditField_2Label_2.Text = 'X-Step Size (mm)';
            app.XStepSizemmEditField_2 = uieditfield(app.SingleMovementPanel, 'numeric');
            app.XStepSizemmEditField_2.ValueDisplayFormat = '%11.3f';
            app.YStepSizemmEditField_2Label = uilabel(app.SingleMovementPanel);
            app.YStepSizemmEditField_2Label.Text = 'Y-Step Size (mm)';
            app.YStepSizemmEditField_2 = uieditfield(app.SingleMovementPanel, 'numeric');
            app.YStepSizemmEditField_2.ValueDisplayFormat = '%11.3f';
            app.HomeButton = uibutton(app.SingleMovementPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.StartScanPanel = uipanel(app.ScannerSetupTab);
            app.StartScanPanel.Title = '4. Start Scan';
            app.StartScanButton = uibutton(app.StartScanPanel, 'push');
            app.StartScanButton.ButtonPushedFcn = createCallbackFcn(app, @StartScanButtonPushed, true);
            app.StartScanButton.Text = 'Start Scan';
            app.LoadDataPanel = uipanel(app.ScannerSetupTab);
            app.LoadDataPanel.Title = '5. Load Data';
            app.LoadDataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ScanNotesEditFieldLabel = uilabel(app.ScannerSetupTab);
            app.ScanNotesEditFieldLabel.Text = 'Scan Notes';
            app.ScanNotesEditField = uieditfield(app.ScannerSetupTab, 'text');
            app.DualRadarSARControllerLabel = uilabel(app.UIFigure);
            app.DualRadarSARControllerLabel.Text = 'Dual Radar SAR Controller';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel = uilabel(app.UIFigure);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel.Text = {'Author: Josiah W. Smith'; 'Advisor: Murat Torlak'};
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.AMC4030ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar2ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar1ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.AMC4030ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.AMC4030ConnectionLabel.Text = 'AMC4030 Connection';
            app.Radar2ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar2ConnectionLabel.Text = 'Radar 2 Connection';
            app.Radar1ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar1ConnectionLabel.Text = 'Radar 1 Connection';
            app.ESP32ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.ESP32ConnectionLabel.Text = 'ESP32 Connection';
            app.ESP32ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar1EditFieldLabel = uilabel(app.ConnectionsPanel);
            app.Radar1EditFieldLabel.Text = 'Radar 1';
            app.Radar1COMEditField = uieditfield(app.ConnectionsPanel, 'text');
            app.Radar1COMEditField.Editable = 'off';
            app.Radar1COMEditField.Value = 'COM00';
            app.Radar2EditFieldLabel = uilabel(app.ConnectionsPanel);
            app.Radar2EditFieldLabel.Text = 'Radar 2';
            app.Radar2COMEditField = uieditfield(app.ConnectionsPanel, 'text');
            app.Radar2COMEditField.Editable = 'off';
            app.Radar2COMEditField.Value = 'COM00';
            app.AMC4030EditFieldLabel = uilabel(app.ConnectionsPanel);
            app.AMC4030EditFieldLabel.Text = 'AMC4030';
            app.AMC4030COMEditField = uieditfield(app.ConnectionsPanel, 'text');
            app.AMC4030COMEditField.Editable = 'off';
            app.AMC4030COMEditField.Value = 'COM00';
            app.ESP32EditFieldLabel = uilabel(app.ConnectionsPanel);
            app.ESP32EditFieldLabel.Text = 'ESP32';
            app.ESP32COMEditField = uieditfield(app.ConnectionsPanel, 'text');
            app.ESP32COMEditField.Editable = 'off';
            app.ESP32COMEditField.Value = 'COM00';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.AMC4030ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar2ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar1ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.AMC4030ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.AMC4030ConfigurationLabel.Text = 'AMC4030 Configuration';
            app.Radar2ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar2ConfigurationLabel.Text = 'Radar 2 Configuration';
            app.Radar1ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar1ConfigurationLabel.Text = 'Radar 1 Configuration';
            app.ESP32ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.ESP32ConfigurationLabel.Text = 'ESP32 Configuration';
            app.ESP32ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MainTextArea = uitextarea(app.UIFigure);
            app.XPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.XPositionmmEditFieldLabel.Text = 'X-Position (mm)';
            app.XPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.XPositionmmEditField.Editable = 'off';
            app.YPositionmmEditFieldLabel = uilabel(app.UIFigure);
            app.YPositionmmEditFieldLabel.Text = 'Y-Position (mm)';
            app.YPositionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPositionmmEditField.ValueDisplayFormat = '%11.3f';
            app.YPositionmmEditField.Editable = 'off';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dual_radar_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end