classdef single_radar_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainTextArea                   matlab.ui.control.TextArea
        ConfigurationPanel             matlab.ui.container.Panel
        ScannerConfigurationLamp       matlab.ui.control.Lamp
        ScannerConfigurationLabel      matlab.ui.control.Label
        Radar1ConfigurationLabel       matlab.ui.control.Label
        MCUConfigurationLabel          matlab.ui.control.Label
        Radar1ConfigurationLamp        matlab.ui.control.Lamp
        MCUConfigurationLamp           matlab.ui.control.Lamp
        ConnectionsPanel               matlab.ui.container.Panel
        ScannerConnectionLamp          matlab.ui.control.Lamp
        ScannerConnectionLabel         matlab.ui.control.Label
        Radar1ConnectionLabel          matlab.ui.control.Label
        MCUConnectionLabel             matlab.ui.control.Label
        Radar1ConnectionLamp           matlab.ui.control.Lamp
        MCUConnectionLamp              matlab.ui.control.Lamp
        AuthorJosiahWSmithAdvisorMuratTorlakLabel  matlab.ui.control.Label
        SingleRadarSARControllerLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        RadarSetupTab                  matlab.ui.container.Tab
        DCA1ConfigurationPanel         matlab.ui.container.Panel
        DCA1PrepareLamp                matlab.ui.control.Lamp
        PrepareDCA1Button              matlab.ui.control.Button
        DataPortEditField              matlab.ui.control.EditField
        DataPortEditFieldLabel         matlab.ui.control.Label
        ConfigPortEditField            matlab.ui.control.EditField
        ConfigPortEditFieldLabel       matlab.ui.control.Label
        DCAIPAddressEditField          matlab.ui.control.EditField
        DCAIPAddressEditFieldLabel     matlab.ui.control.Label
        SystemIPAddressEditField       matlab.ui.control.EditField
        SystemIPAddressEditFieldLabel  matlab.ui.control.Label
        SerialConnectionsPanel         matlab.ui.container.Panel
        ConnectRadar1Button            matlab.ui.control.Button
        Radar1ConfigurationPanel       matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        Tx2CheckBox                    matlab.ui.control.CheckBox
        Tx1CheckBox                    matlab.ui.control.CheckBox
        Tx0CheckBox                    matlab.ui.control.CheckBox
        Rx2CheckBox                    matlab.ui.control.CheckBox
        Rx3CheckBox                    matlab.ui.control.CheckBox
        Rx1CheckBox                    matlab.ui.control.CheckBox
        Rx0CheckBox                    matlab.ui.control.CheckBox
        ConfigureRadar1Button          matlab.ui.control.Button
        PeriodicitymsEditField         matlab.ui.control.NumericEditField
        PeriodicitymsEditFieldLabel    matlab.ui.control.Label
        NoofChirpLoopsEditField        matlab.ui.control.NumericEditField
        NoofChirpLoopsEditFieldLabel   matlab.ui.control.Label
        NoofFramesEditField            matlab.ui.control.NumericEditField
        NoofFramesEditFieldLabel       matlab.ui.control.Label
        RampEndTimeusEditField         matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel    matlab.ui.control.Label
        SampleRatekspsEditField        matlab.ui.control.NumericEditField
        SampleRatekspsEditFieldLabel   matlab.ui.control.Label
        ADCSamplesEditField            matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel       matlab.ui.control.Label
        ADCStartTimeusEditField        matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel   matlab.ui.control.Label
        TXStartTimeusEditField         matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel    matlab.ui.control.Label
        IdleTimeusEditField            matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel       matlab.ui.control.Label
        FreqSlopeMHzusEditField        matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel   matlab.ui.control.Label
        StartFreqGHzEditField          matlab.ui.control.NumericEditField
        StartFreqGHzEditFieldLabel     matlab.ui.control.Label
        MCUSetupTab                    matlab.ui.container.Tab
        SerialConnectionsPanel_2       matlab.ui.container.Panel
        ConnectMCUButton               matlab.ui.control.Button
        ScannerSetupTab                matlab.ui.container.Tab
        SerialConnectionsPanel_3       matlab.ui.container.Panel
        ConnectScannerButton           matlab.ui.control.Button
    end

    properties
        mmWavePath = "C:\ti\mmwave_studio_02_01_01_00\mmWaveStudio"

        dca1
        dca2
        radar1
        radar2
        scanner
        esp
        amc
    end

    methods (Access = private)

        function setupRadar(app)
            app.radar1.connectionLamp = app.Radar1ConnectionLamp;
            app.radar1.configurationLamp = app.Radar1ConfigurationLamp;
        end

        function setupESP(app)
            app.esp.connectionLamp = app.MCUConnectionLamp;
            app.esp.configurationLamp = app.MCUConfigurationLamp;
        end

        function setupDCA(app)
            app.dca1.prepareLamp = app.DCA1PrepareLamp;
        end

        function setupAMC(app)
            app.scanner.connectionLamp = app.ScannerConnectionLamp;
            app.scanner.configurationLamp = app.ScannerConfigurationLamp;
        end
        
        function setupScanner(app)
            % TODO: create setupScanner
        end

        function NumChirpLoops = checkRadarParameters(app,radarObj,NumChirpLoops,ADCSamples)
            % Ensure number of chirp loops is multiple of 4
            if mod(NumChirpLoops,4) ~= 0
                % Reduce to value that is multiple of 4
                NumChirpLoops = NumChirpLoops - mod(NumChirpLoops,4);
            end

            % Ensure memory usage is OK
            if ADCSamples * NumChirpLoops > 16384
                app.MainTextArea.Value = "Out of limits on chirp parameters!";
                radarObj.configurationLamp.Color = 'red';

                cpN = 16384/ADCSamples;
                adcN = 16384/NumChirpLoops;
                app.MainTextArea.Value = "To resolve reduce to " + floor(cpN) + " chirp loops " + " - Reduce to " + floor(adcN) + " ADC samples";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the folders to the path
            splitPath = regexp(cd,filesep,'split');
            if string(splitPath{end}) ~= "dual-radar-gui"
                try
                    cd(fileparts(which('dual_radar_gui.mlapp')));
                catch
                end
            end
            splitPath = regexp(cd,filesep,'split');
            while string(splitPath{end}) ~= "dual-radar-gui"
                selection = uiconfirm(app.UIFigure,'Would you liked to choose the correct "dual-radar-gui" folder?','Incorrect opening folder',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Cound not find correct folder!");
                    UIFigureCloseRequest(app, 0);
                    return;
                end

                drawnow
                figure(app.UIFigure)
                directoryname = uigetdir("","Select ""dual-radar-gui"" Path");
                cd(directoryname);
                splitPath = regexp(cd,filesep,'split');
            end
            addpath(genpath("./"));

            % Set the mmWaveStudio path, TODO: check that the path exists
            splitPath = regexp(app.mmWavePath,filesep,'split');
            while string(splitPath{end}) ~= "mmWaveStudio"
                app.mmWavePath = uigetdir('C:\',"Select mmWaveStudio Installation Path");
                splitPath = regexp(app.mmWavePath,filesep,'split');
            end
            app.MainTextArea.Value = "mmWaveStudio path properly set to " + app.mmWavePath;

            app.dca1 = DCA_Device(app,1);
            setupDCA(app);

            app.radar1 = TI_Radar_Device(app,1);
            setupRadar(app);

            % TODO: change this to AMC4030_Device
            app.amc = AMC4030_Device(app);
            setupAMC(app);
            
            app.scanner = SAR_Scanner_Device(app,app.amc,79);
            setupScanner(app);

            app.esp = ESP32_Device(app);
            setupESP(app);
        end

        % Button pushed function: ConnectRadar1Button
        function ConnectRadar1ButtonPushed(app, event)
            app.radar1.SerialConnect(app);
        end

        % Button pushed function: ConfigureRadar1Button
        function ConfigureRadar1ButtonPushed(app, event)
            % Change num property
            if app.StartFreqGHzEditField.Value == 60
                app.radar1.num = 1;
            elseif app.StartFreqGHzEditField.Value == 77
                app.radar1.num = 2;
            else
                app.StartFreqGHzEditField.Value = 77;
                app.radar1.num = 2;
            end

            % Check radar parameters
            app.NoofChirpLoopsEditField.Value = checkRadarParameters(app,app.radar1,app.NoofChirpLoopsEditField.Value,app.ADCSamplesEditField.Value);

            % Set radar1 properties
            app.radar1.f0_GHz = app.StartFreqGHzEditField.Value;
            app.radar1.K = app.FreqSlopeMHzusEditField.Value;
            app.radar1.idleTime_us = app.IdleTimeusEditField.Value;
            app.radar1.txStartTime_us = app.TXStartTimeusEditField.Value;
            app.radar1.adcStartTime_us = app.ADCStartTimeusEditField.Value;
            app.radar1.adcSamples = app.ADCSamplesEditField.Value;
            app.radar1.fS_ksps = app.SampleRatekspsEditField.Value;
            app.radar1.rampEndTime_us = app.RampEndTimeusEditField.Value;
            app.radar1.numFrames = app.NoofFramesEditField.Value;
            app.radar1.numChirps = app.NoofChirpLoopsEditField.Value;
            app.radar1.pri_ms = app.PeriodicitymsEditField.Value;
            app.radar1.nRx = 4;
            app.radar1.nTx = app.Tx0CheckBox.Value + app.Tx1CheckBox.Value + app.Tx2CheckBox.Value;

            app.radar1.Configure();
        end

        % Button pushed function: PrepareDCA1Button
        function PrepareDCA1ButtonPushed(app, event)
            % Set dca1 properties
            app.dca1.systemIPAddress = app.SystemIPAddressEditField.Value;
            app.dca1.DCA1000IPAddress = app.DCAIPAddressEditField.Value;
            app.dca1.configPort = app.ConfigPortEditField.Value;
            app.dca1.dataPort = app.DataPortEditField.Value;

            app.dca1.Prepare();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.dca1.Start();
            app.radar1.Start();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.radar1.Stop();
            app.dca1.Stop();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.Radar1ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.Radar1ConfigurationPanel.Title = '3. Radar 1 Configuration';
            app.StartFreqGHzEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.StartFreqGHzEditFieldLabel.Text = 'Start Freq (GHz)';
            app.StartFreqGHzEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.StartFreqGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartFreqGHzEditField.Value = 77;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.Value = 124.996;
            app.IdleTimeusEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.Value = 10;
            app.TXStartTimeusEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCSamplesEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueDisplayFormat = '%11.3g';
            app.ADCSamplesEditField.Value = 64;
            app.SampleRatekspsEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.SampleRatekspsEditFieldLabel.Text = 'Sample Rate (ksps)';
            app.SampleRatekspsEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.SampleRatekspsEditField.Limits = [2000 37500];
            app.SampleRatekspsEditField.ValueDisplayFormat = '%11.3f';
            app.SampleRatekspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.Value = 32;
            app.NoofFramesEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.NoofFramesEditFieldLabel.Text = 'No of Frames';
            app.NoofFramesEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.NoofFramesEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.NoofChirpLoopsEditFieldLabel.Text = 'No of Chirp Loops';
            app.NoofChirpLoopsEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.NoofChirpLoopsEditField.Limits = [0 Inf];
            app.NoofChirpLoopsEditField.RoundFractionalValues = 'on';
            app.NoofChirpLoopsEditField.ValueDisplayFormat = '%11.3g';
            app.NoofChirpLoopsEditField.Value = 4;
            app.PeriodicitymsEditFieldLabel = uilabel(app.Radar1ConfigurationPanel);
            app.PeriodicitymsEditFieldLabel.Text = 'Periodicity (ms)';
            app.PeriodicitymsEditField = uieditfield(app.Radar1ConfigurationPanel, 'numeric');
            app.PeriodicitymsEditField.ValueDisplayFormat = '%11.3f';
            app.PeriodicitymsEditField.Value = 50;
            app.ConfigureRadar1Button = uibutton(app.Radar1ConfigurationPanel, 'push');
            app.ConfigureRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRadar1ButtonPushed, true);
            app.ConfigureRadar1Button.Text = 'Configure Radar 1';
            app.Rx0CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Rx0CheckBox.Enable = 'off';
            app.Rx0CheckBox.Text = 'Rx0';
            app.Rx0CheckBox.Value = true;
            app.Rx1CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Rx1CheckBox.Enable = 'off';
            app.Rx1CheckBox.Text = 'Rx1';
            app.Rx1CheckBox.Value = true;
            app.Rx3CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Rx3CheckBox.Enable = 'off';
            app.Rx3CheckBox.Text = 'Rx3';
            app.Rx3CheckBox.Value = true;
            app.Rx2CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Rx2CheckBox.Enable = 'off';
            app.Rx2CheckBox.Text = 'Rx2';
            app.Rx2CheckBox.Value = true;
            app.Tx0CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Tx0CheckBox.Enable = 'off';
            app.Tx0CheckBox.Text = 'Tx0';
            app.Tx0CheckBox.Value = true;
            app.Tx1CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Tx1CheckBox.Enable = 'off';
            app.Tx1CheckBox.Text = 'Tx1';
            app.Tx2CheckBox = uicheckbox(app.Radar1ConfigurationPanel);
            app.Tx2CheckBox.Enable = 'off';
            app.Tx2CheckBox.Text = 'Tx2';
            app.Tx2CheckBox.Value = true;
            app.StartButton = uibutton(app.Radar1ConfigurationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Radar1ConfigurationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SerialConnectionsPanel = uipanel(app.RadarSetupTab);
            app.SerialConnectionsPanel.Title = '1. Serial Connection(s)';
            app.ConnectRadar1Button = uibutton(app.SerialConnectionsPanel, 'push');
            app.ConnectRadar1Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectRadar1ButtonPushed, true);
            app.ConnectRadar1Button.Text = 'Connect Radar 1';
            app.DCA1ConfigurationPanel = uipanel(app.RadarSetupTab);
            app.DCA1ConfigurationPanel.Title = '2. DCA 1 Configuration';
            app.SystemIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.SystemIPAddressEditFieldLabel.Text = 'System IP Address';
            app.SystemIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.SystemIPAddressEditField.Value = '192.168.33.30';
            app.DCAIPAddressEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DCAIPAddressEditFieldLabel.Text = 'DCA IP Address';
            app.DCAIPAddressEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DCAIPAddressEditField.Value = '192.168.33.180';
            app.ConfigPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.ConfigPortEditFieldLabel.Text = 'Config Port';
            app.ConfigPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.ConfigPortEditField.Value = '4096';
            app.DataPortEditFieldLabel = uilabel(app.DCA1ConfigurationPanel);
            app.DataPortEditFieldLabel.Text = 'Data Port';
            app.DataPortEditField = uieditfield(app.DCA1ConfigurationPanel, 'text');
            app.DataPortEditField.Value = '4098';
            app.PrepareDCA1Button = uibutton(app.DCA1ConfigurationPanel, 'push');
            app.PrepareDCA1Button.ButtonPushedFcn = createCallbackFcn(app, @PrepareDCA1ButtonPushed, true);
            app.PrepareDCA1Button.Text = 'Prepare DCA 1';
            app.DCA1PrepareLamp = uilamp(app.DCA1ConfigurationPanel);
            app.MCUSetupTab = uitab(app.TabGroup);
            app.MCUSetupTab.Title = 'MCU Setup';
            app.SerialConnectionsPanel_2 = uipanel(app.MCUSetupTab);
            app.SerialConnectionsPanel_2.Title = '1. Serial Connection(s)';
            app.ConnectMCUButton = uibutton(app.SerialConnectionsPanel_2, 'push');
            app.ConnectMCUButton.Text = 'Connect MCU';
            app.ScannerSetupTab = uitab(app.TabGroup);
            app.ScannerSetupTab.Title = 'Scanner Setup';
            app.SerialConnectionsPanel_3 = uipanel(app.ScannerSetupTab);
            app.SerialConnectionsPanel_3.Title = '1. Serial Connection(s)';
            app.ConnectScannerButton = uibutton(app.SerialConnectionsPanel_3, 'push');
            app.ConnectScannerButton.Text = 'Connect Scanner';
            app.SingleRadarSARControllerLabel = uilabel(app.UIFigure);
            app.SingleRadarSARControllerLabel.Text = 'Single Radar SAR Controller';
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel = uilabel(app.UIFigure);
            app.AuthorJosiahWSmithAdvisorMuratTorlakLabel.Text = {'Author: Josiah W. Smith'; 'Advisor: Murat Torlak'};
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.MCUConnectionLamp = uilamp(app.ConnectionsPanel);
            app.Radar1ConnectionLamp = uilamp(app.ConnectionsPanel);
            app.MCUConnectionLabel = uilabel(app.ConnectionsPanel);
            app.MCUConnectionLabel.Text = 'MCU Connection';
            app.Radar1ConnectionLabel = uilabel(app.ConnectionsPanel);
            app.Radar1ConnectionLabel.Text = 'Radar 1 Connection';
            app.ScannerConnectionLabel = uilabel(app.ConnectionsPanel);
            app.ScannerConnectionLabel.Text = 'Scanner Connection';
            app.ScannerConnectionLamp = uilamp(app.ConnectionsPanel);
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.MCUConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.Radar1ConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MCUConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.MCUConfigurationLabel.Text = 'MCU Configuration';
            app.Radar1ConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.Radar1ConfigurationLabel.Text = 'Radar 1 Configuration';
            app.ScannerConfigurationLabel = uilabel(app.ConfigurationPanel);
            app.ScannerConfigurationLabel.Text = 'Scanner Configuration';
            app.ScannerConfigurationLamp = uilamp(app.ConfigurationPanel);
            app.MainTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = single_radar_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end